classdef GUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        INPUTPARAMETERSLabel  matlab.ui.control.Label
        MODELSLabel           matlab.ui.control.Label
        PLOTLabel             matlab.ui.control.Label
        NSliderLabel          matlab.ui.control.Label
        NSlider               matlab.ui.control.Slider
        BetaSliderLabel       matlab.ui.control.Label
        BetaSlider            matlab.ui.control.Slider
        UIAxes                matlab.ui.control.UIAxes
        SEIRButton            matlab.ui.control.Button
        SEIHRButton           matlab.ui.control.Button
        SEIRDButton           matlab.ui.control.Button
        SEIHRDButton          matlab.ui.control.Button
        SEIHRFDButton         matlab.ui.control.Button
        LOADDATALabel         matlab.ui.control.Label
        Liberia2014Button     matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: NSlider
        function N(app, event)
            Nval = app.NSlider.Value;
        end

        % Value changed function: BetaSlider
        function Beta(app, event)
            betaval = app.BetaSlider.Value;
        end

        % Button pushed function: SEIRButton
        function SEIR(app, event)
        plot(app.UIAxes, 0, 0);        
        N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
        E    = 32;
        S    = N-E;

        beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_HR = 0.062;
        beta_HD = 0.062;
        beta_F  = 0.489;
        theta   = 0.45049;
        alpha   = 0.088883;
        e_1     = 0.066667;
        e_2     = 0.3086;
        k_2     = 0.3086;
        k_1     = 0.07513148;
        pie     = 0.197;
        roe     = 0.06297229;
        delta   = 0.09930487;
        gamma   = 0.4975124;   
        
        tspan = 1:1:254;
        f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                    (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                    (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                    theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                    (1-pie)*e_1*x(3)];
        [t,xa]=ode45(f,tspan, [S E 0 0 0]);
        
        hold(app.UIAxes, 'on');
        plot(app.UIAxes,t-100,xa(:,1),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,2),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,3)+xa(:,4),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,5),'LineWidth',3);
        legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered');
        hold(app.UIAxes, 'off');
        
        end

        % Button pushed function: SEIHRButton
        function SEIHR(app, event)
        plot(app.UIAxes, 0, 0);        
        N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
        E    = 32;
        S    = N-E;

        beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_HR = 0.062;
        beta_HD = 0.062;
        beta_F  = 0.489;
        theta   = 0.45049;
        alpha   = 0.088883;
        e_1     = 0.066667;
        e_2     = 0.3086;
        k_2     = 0.3086;
        k_1     = 0.07513148;
        pie     = 0.197;
        roe     = 0.06297229;
        delta   = 0.09930487;
        gamma   = 0.4975124;   
        
        tspan = 1:1:254;
        f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                    (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                    (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                    theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                    pie*e_2*x(3)-roe*x(5);
                    pie*k_2*x(4)-delta*x(6);
                    (1-pie)*e_1*x(3)+roe*x(5)];
        [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
        
        hold(app.UIAxes, 'on');
        plot(app.UIAxes,t-100,xa(:,1),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,2),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,3)+xa(:,4),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,5)+xa(:,6),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,7),'LineWidth',3);
        legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
        hold(app.UIAxes, 'off');
        end

        % Button pushed function: SEIRDButton
        function SEIRD(app, event)
        plot(app.UIAxes, 0, 0);        
        N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
        E    = 32;
        S    = N-E;

        beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_HR = 0.062;
        beta_HD = 0.062;
        beta_F  = 0.489;
        theta   = 0.45049;
        alpha   = 0.088883;
        e_1     = 0.066667;
        e_2     = 0.3086;
        k_2     = 0.3086;
        k_1     = 0.07513148;
        pie     = 0.197;
        roe     = 0.06297229;
        delta   = 0.09930487;
        gamma   = 0.4975124;    
        
        tspan = 1:1:254;
        f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                    (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                    (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                    theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                    (1-pie)*e_1*x(3);
                    ((1-pie)*k_1*x(4))*gamma];
        [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
        
        hold(app.UIAxes, 'on');
        plot(app.UIAxes,t-100,xa(:,1),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,2),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,3)+xa(:,4),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,5),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,6),'LineWidth',3);
        legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered','Deceased');
        hold(app.UIAxes, 'off');
        end

        % Button pushed function: SEIHRDButton
        function SEIHRD(app, event)
        plot(app.UIAxes, 0, 0);        
        N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
        E    = 32;
        S    = N-E;

        beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_HR = 0.062;
        beta_HD = 0.062;
        beta_F  = 0.489;
        theta   = 0.45049;
        alpha   = 0.088883;
        e_1     = 0.066667;
        e_2     = 0.3086;
        k_2     = 0.3086;
        k_1     = 0.07513148;
        pie     = 0.197;
        roe     = 0.06297229;
        delta   = 0.09930487;
        gamma   = 0.4975124;    
        
        tspan = 1:1:254;
        f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                    (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                    (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                    theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                    pie*e_2*x(3)-roe*x(5);
                    pie*k_2*x(4)-delta*x(6);
                    (1-pie)*e_1*x(3)+roe*x(5);
                    delta*x(6)];
        [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
        
        hold(app.UIAxes, 'on');
        plot(app.UIAxes,t-100,xa(:,1),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,2),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,3)+xa(:,4),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,5)+xa(:,6),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,7),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,8),'LineWidth',3);
        legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
        hold(app.UIAxes, 'off');        
        end

        % Button pushed function: SEIHRFDButton
        function SEIHRFD(app, event)
        plot(app.UIAxes, 0, 0);        
        N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
        E    = 32;
        S    = N-E;

        beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
        beta_HR = 0.062;
        beta_HD = 0.062;
        beta_F  = 0.489;
        theta   = 0.45049;
        alpha   = 0.088883;
        e_1     = 0.066667;
        e_2     = 0.3086;
        k_2     = 0.3086;
        k_1     = 0.07513148;
        pie     = 0.197;
        roe     = 0.06297229;
        delta   = 0.09930487;
        gamma   = 0.4975124;
        
        
        tspan = 1:1:254;
        f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                    (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                    (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                    theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                    pie*e_2*x(3)-roe*x(5);
                    pie*k_2*x(4)-delta*x(6);
                    (1-pie)*e_1*x(3)+roe*x(5);
                    (1-pie)*k_1*x(4)-gamma*x(8);
                    gamma*x(8)+delta*x(6)];
        [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);

        hold(app.UIAxes, 'on');
        plot(app.UIAxes,t-100,xa(:,1),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,2),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,3)+xa(:,4),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,5)+xa(:,6),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,7),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,8),'LineWidth',3);
        plot(app.UIAxes,t-100,xa(:,9),'LineWidth',3);
        legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
        hold(app.UIAxes, 'off');

        end

        % Button pushed function: Liberia2014Button
        function Upload(app, event)
        [filename, filepath, ~] = uigetfile('*.xlsx');
        [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
        
        tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
        I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
        D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.INPUTPARAMETERSLabel = uilabel(app.UIFigure);
            app.INPUTPARAMETERSLabel.Text = 'INPUT PARAMETERS';
            app.MODELSLabel = uilabel(app.UIFigure);
            app.MODELSLabel.Text = 'MODELS';
            app.PLOTLabel = uilabel(app.UIFigure);
            app.PLOTLabel.Text = 'PLOT';
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [0 30000];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @N, true);
            app.BetaSliderLabel = uilabel(app.UIFigure);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.UIFigure);
            app.BetaSlider.Limits = [0 1];
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @Beta, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Compartmental Model')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Number of people')
            app.SEIRButton = uibutton(app.UIFigure, 'push');
            app.SEIRButton.ButtonPushedFcn = createCallbackFcn(app, @SEIR, true);
            app.SEIRButton.Text = 'SEIR';
            app.SEIHRButton = uibutton(app.UIFigure, 'push');
            app.SEIHRButton.ButtonPushedFcn = createCallbackFcn(app, @SEIHR, true);
            app.SEIHRButton.Text = 'SEIHR';
            app.SEIRDButton = uibutton(app.UIFigure, 'push');
            app.SEIRDButton.ButtonPushedFcn = createCallbackFcn(app, @SEIRD, true);
            app.SEIRDButton.Text = 'SEIRD';
            app.SEIHRDButton = uibutton(app.UIFigure, 'push');
            app.SEIHRDButton.ButtonPushedFcn = createCallbackFcn(app, @SEIHRD, true);
            app.SEIHRDButton.Text = 'SEIHRD';
            app.SEIHRFDButton = uibutton(app.UIFigure, 'push');
            app.SEIHRFDButton.ButtonPushedFcn = createCallbackFcn(app, @SEIHRFD, true);
            app.SEIHRFDButton.Text = 'SEIHRFD';
            app.LOADDATALabel = uilabel(app.UIFigure);
            app.LOADDATALabel.Text = 'LOAD DATA';
            app.Liberia2014Button = uibutton(app.UIFigure, 'push');
            app.Liberia2014Button.ButtonPushedFcn = createCallbackFcn(app, @Upload, true);
            app.Liberia2014Button.Text = 'Liberia 2014';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end