classdef GUI_11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AgentbasedModelTab              matlab.ui.container.Tab
        MODELDIMENSIONSLabel            matlab.ui.control.Label
        NTotalPopulationSlider_3        matlab.ui.control.Slider
        NTotalPopulationSlider_2Label_3  matlab.ui.control.Label
        BetaCommunityContactRateSlider_4  matlab.ui.control.Slider
        BetaCommunityContactRateSlider_2Label_4  matlab.ui.control.Label
        TimedaysKnob_2Label             matlab.ui.control.Label
        TimedaysKnob_2                  matlab.ui.control.Knob
        INPUTPARAMETERSLabel_3          matlab.ui.control.Label
        IKnobLabel                      matlab.ui.control.Label
        IKnob                           matlab.ui.control.Knob
        RKnobLabel                      matlab.ui.control.Label
        RKnob                           matlab.ui.control.Knob
        PLOTLabel_3                     matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        CONTROLSLabel_3                 matlab.ui.control.Label
        CLEARButton_3                   matlab.ui.control.Button
        STARTButton_3                   matlab.ui.control.Button
        PMovementSpinnerLabel           matlab.ui.control.Label
        PMovementSpinner                matlab.ui.control.Spinner
        PTransmitSpinnerLabel           matlab.ui.control.Label
        PTransmitSpinner                matlab.ui.control.Spinner
        PDeathUSpinnerLabel             matlab.ui.control.Label
        PDeathUSpinner                  matlab.ui.control.Spinner
        PDeathHSpinnerLabel             matlab.ui.control.Label
        PDeathHSpinner                  matlab.ui.control.Spinner
        PSeekingHSpinnerLabel           matlab.ui.control.Label
        PSeekingHSpinner                matlab.ui.control.Spinner
        SusceptibleTextAreaLabel        matlab.ui.control.Label
        SusceptibleTextArea             matlab.ui.control.TextArea
        InfectedTextArea_2Label         matlab.ui.control.Label
        InfectedTextArea_2              matlab.ui.control.TextArea
        RecoveredTextArea_2Label        matlab.ui.control.Label
        RecoveredTextArea_2             matlab.ui.control.TextArea
        Knob                            matlab.ui.control.Knob
        FINALLabel                      matlab.ui.control.Label
        CONDITIONSLabel                 matlab.ui.control.Label
        InfectedTextArea_3Label         matlab.ui.control.Label
        InfectedTextArea_3              matlab.ui.control.TextArea
        RecoveredTextArea_3Label        matlab.ui.control.Label
        RecoveredTextArea_3             matlab.ui.control.TextArea
        DeadTextArea_2Label             matlab.ui.control.Label
        DeadTextArea_2                  matlab.ui.control.TextArea
        HospitalizedTextArea_2Label     matlab.ui.control.Label
        HospitalizedTextArea_2          matlab.ui.control.TextArea
        FuneralTextArea_2Label          matlab.ui.control.Label
        FuneralTextArea_2               matlab.ui.control.TextArea
        EffectivePopSpinnerLabel        matlab.ui.control.Label
        EffectivePopSpinner             matlab.ui.control.Spinner
        ValidationAgentbasedTab         matlab.ui.container.Tab
        MODELDIMENSIONSLabel_2          matlab.ui.control.Label
        NTotalPopulationSlider_4        matlab.ui.control.Slider
        NTotalPopulationSlider_2Label_4  matlab.ui.control.Label
        BetaCommunityContactRateSlider_5  matlab.ui.control.Slider
        BetaCommunityContactRateSlider_2Label_5  matlab.ui.control.Label
        INPUTPARAMETERSLabel_4          matlab.ui.control.Label
        PLOTLabel_4                     matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        CONTROLSLabel_4                 matlab.ui.control.Label
        CLEARButton_4                   matlab.ui.control.Button
        STARTButton_4                   matlab.ui.control.Button
        Knob_2                          matlab.ui.control.Knob
        ErrorLabel                      matlab.ui.control.Label
        TimedaysKnob_3Label             matlab.ui.control.Label
        TimedaysKnob_3                  matlab.ui.control.Knob
        IKnob_2Label                    matlab.ui.control.Label
        IKnob_2                         matlab.ui.control.Knob
        RKnob_2Label                    matlab.ui.control.Label
        RKnob_2                         matlab.ui.control.Knob
        PMovementSpinner_2Label         matlab.ui.control.Label
        PMovementSpinner_2              matlab.ui.control.Spinner
        PTransmitSpinner_2Label         matlab.ui.control.Label
        PTransmitSpinner_2              matlab.ui.control.Spinner
        PDeathUSpinner_2Label           matlab.ui.control.Label
        PDeathUSpinner_2                matlab.ui.control.Spinner
        PDeathHSpinner_2Label           matlab.ui.control.Label
        PDeathHSpinner_2                matlab.ui.control.Spinner
        PSeekingHSpinner_2Label         matlab.ui.control.Label
        PSeekingHSpinner_2              matlab.ui.control.Spinner
        SusceptibleTextArea_2Label      matlab.ui.control.Label
        SusceptibleTextArea_2           matlab.ui.control.TextArea
        InfectedTextArea_4Label         matlab.ui.control.Label
        InfectedTextArea_4              matlab.ui.control.TextArea
        RecoveredTextArea_4Label        matlab.ui.control.Label
        RecoveredTextArea_4             matlab.ui.control.TextArea
        RMSETextArea_2Label             matlab.ui.control.Label
        RMSETextArea_2                  matlab.ui.control.TextArea
        CompartmentalModelTab           matlab.ui.container.Tab
        CompartmentsButtonGroup         matlab.ui.container.ButtonGroup
        SEIRButton                      matlab.ui.control.RadioButton
        SEIHRButton                     matlab.ui.control.RadioButton
        SEIRDButton                     matlab.ui.control.RadioButton
        SEIHRDButton                    matlab.ui.control.RadioButton
        SEIHRFDButton                   matlab.ui.control.RadioButton
        FINISHButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        INPUTPARAMETERSLabel            matlab.ui.control.Label
        CONTROLSLabel                   matlab.ui.control.Label
        MODELSLabel                     matlab.ui.control.Label
        PLOTLabel                       matlab.ui.control.Label
        CLEARButton                     matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        NTotalPopulationSlider          matlab.ui.control.Slider
        BetaCommunityContactRateSlider  matlab.ui.control.Slider
        TimedaysKnobLabel               matlab.ui.control.Label
        TimedaysKnob                    matlab.ui.control.Knob
        SusceptiblesTextAreaLabel       matlab.ui.control.Label
        SusceptiblesTextArea            matlab.ui.control.TextArea
        FuneralTextAreaLabel            matlab.ui.control.Label
        FuneralTextArea                 matlab.ui.control.TextArea
        InfectedTextAreaLabel           matlab.ui.control.Label
        InfectedTextArea                matlab.ui.control.TextArea
        RecoveredTextAreaLabel          matlab.ui.control.Label
        RecoveredTextArea               matlab.ui.control.TextArea
        ExposedTextAreaLabel            matlab.ui.control.Label
        ExposedTextArea                 matlab.ui.control.TextArea
        HospitalizedTextAreaLabel       matlab.ui.control.Label
        HospitalizedTextArea            matlab.ui.control.TextArea
        DeadTextAreaLabel               matlab.ui.control.Label
        DeadTextArea                    matlab.ui.control.TextArea
        MODELSPECIFICPARAMETERSLabel    matlab.ui.control.Label
        SEIRPanel                       matlab.ui.container.Panel
        AlphaIncubationPeriodSliderLabel  matlab.ui.control.Label
        AlphaIncubationPeriodSlider     matlab.ui.control.Slider
        ThetaProbabilityofSurvivalSliderLabel  matlab.ui.control.Label
        ThetaProbabilityofSurvivalSlider  matlab.ui.control.Slider
        PiProbabilityofSeekingHospitalizationSliderLabel  matlab.ui.control.Label
        PiProbabilityofSeekingHospitalizationSlider  matlab.ui.control.Slider
        e1TimefromInfectiontoRecoverySliderLabel  matlab.ui.control.Label
        e1TimefromInfectiontoRecoverySlider  matlab.ui.control.Slider
        e2k2TimeuntilHospitalizationSliderLabel  matlab.ui.control.Label
        e2k2TimeuntilHospitalizationSlider  matlab.ui.control.Slider
        k1TimefromInfectiontoDeathSliderLabel  matlab.ui.control.Label
        k1TimefromInfectiontoDeathSlider  matlab.ui.control.Slider
        NTotalPopulationSlider_2Label_2  matlab.ui.control.Label
        BetaCommunityContactRateSlider_2Label_2  matlab.ui.control.Label
        HPanel                          matlab.ui.container.Panel
        BetaHHospitalContactRateSliderLabel  matlab.ui.control.Label
        BetaHHospitalContactRateSlider  matlab.ui.control.Slider
        DeltaTimefromHospitalizationtoDeathSliderLabel  matlab.ui.control.Label
        DeltaTimefromHospitalizationtoDeathSlider  matlab.ui.control.Slider
        RoeTimefromHospitalizationtoRecoverySliderLabel  matlab.ui.control.Label
        RoeTimefromHospitalizationtoRecoverySlider  matlab.ui.control.Slider
        DPanel                          matlab.ui.container.Panel
        GammaDurationofBurialTimeSliderLabel  matlab.ui.control.Label
        GammaDurationofBurialTimeSlider  matlab.ui.control.Slider
        FPanel                          matlab.ui.container.Panel
        BetaFFuneralContactRateSliderLabel  matlab.ui.control.Label
        BetaFFuneralContactRateSlider   matlab.ui.control.Slider
        INITIALCONDITIONSLabel          matlab.ui.control.Label
        EKnobLabel                      matlab.ui.control.Label
        EKnob                           matlab.ui.control.Knob
        InfectedSpinnerLabel            matlab.ui.control.Label
        InfectedSpinner                 matlab.ui.control.Spinner
        RecoveredSpinnerLabel           matlab.ui.control.Label
        RecoveredSpinner                matlab.ui.control.Spinner
        DeadSpinnerLabel                matlab.ui.control.Label
        DeadSpinner                     matlab.ui.control.Spinner
        HospitalizationSpinnerLabel     matlab.ui.control.Label
        HospitalizationSpinner          matlab.ui.control.Spinner
        FuneralSpinnerLabel             matlab.ui.control.Label
        FuneralSpinner                  matlab.ui.control.Spinner
        ValidationCompartmentalTab      matlab.ui.container.Tab
        CompartmentsButtonGroup_2       matlab.ui.container.ButtonGroup
        SEIRButton_2                    matlab.ui.control.RadioButton
        SEIHRButton_2                   matlab.ui.control.RadioButton
        SEIRDButton_2                   matlab.ui.control.RadioButton
        SEIHRDButton_2                  matlab.ui.control.RadioButton
        SEIHRFDButton_2                 matlab.ui.control.RadioButton
        UIAxes_2                        matlab.ui.control.UIAxes
        INPUTPARAMETERSLabel_2          matlab.ui.control.Label
        CONTROLSLabel_2                 matlab.ui.control.Label
        MODELSLabel_2                   matlab.ui.control.Label
        PLOTLabel_2                     matlab.ui.control.Label
        CLEARButton_2                   matlab.ui.control.Button
        NTotalPopulationSlider_2Label   matlab.ui.control.Label
        NTotalPopulationSlider_2        matlab.ui.control.Slider
        BetaCommunityContactRateSlider_2  matlab.ui.control.Slider
        STARTButton_2                   matlab.ui.control.Button
        RMSETextAreaLabel               matlab.ui.control.Label
        RMSETextArea                    matlab.ui.control.TextArea
        ERRORLabel                      matlab.ui.control.Label
        BetaCommunityContactRateSlider_2Label_3  matlab.ui.control.Label
        MODELSPECIFICPARAMETERSLabel_2  matlab.ui.control.Label
        SEIRPanel_2                     matlab.ui.container.Panel
        AlphaIncubationPeriodSlider_2Label  matlab.ui.control.Label
        AlphaIncubationPeriodSlider_2   matlab.ui.control.Slider
        ThetaProbabilityofSurvivalSlider_2Label  matlab.ui.control.Label
        ThetaProbabilityofSurvivalSlider_2  matlab.ui.control.Slider
        PiProbabilityofSeekingHospitalizationSlider_2Label  matlab.ui.control.Label
        PiProbabilityofSeekingHospitalizationSlider_2  matlab.ui.control.Slider
        e1TimefromInfectiontoRecoverySlider_2Label  matlab.ui.control.Label
        e1TimefromInfectiontoRecoverySlider_2  matlab.ui.control.Slider
        e2k2TimeuntilHospitalizationSlider_2Label  matlab.ui.control.Label
        e2k2TimeuntilHospitalizationSlider_2  matlab.ui.control.Slider
        k1TimefromInfectiontoDeathSlider_2Label  matlab.ui.control.Label
        k1TimefromInfectiontoDeathSlider_2  matlab.ui.control.Slider
        HPanel_2                        matlab.ui.container.Panel
        BetaHHospitalContactRateSlider_2Label  matlab.ui.control.Label
        BetaHHospitalContactRateSlider_2  matlab.ui.control.Slider
        DeltaTimefromHospitalizationtoDeathSlider_2Label  matlab.ui.control.Label
        DeltaTimefromHospitalizationtoDeathSlider_2  matlab.ui.control.Slider
        RoeTimefromHospitalizationtoRecoverySlider_2Label  matlab.ui.control.Label
        RoeTimefromHospitalizationtoRecoverySlider_2  matlab.ui.control.Slider
        DPanel_2                        matlab.ui.container.Panel
        GammaDurationofBurialTimeSlider_2Label  matlab.ui.control.Label
        GammaDurationofBurialTimeSlider_2  matlab.ui.control.Slider
        FPanel_2                        matlab.ui.container.Panel
        BetaFFuneralContactRateSlider_2Label  matlab.ui.control.Label
        BetaFFuneralContactRateSlider_2  matlab.ui.control.Slider
        INITIALCONDITIONSLabel_2        matlab.ui.control.Label
        EKnob_2Label                    matlab.ui.control.Label
        EKnob_2                         matlab.ui.control.Knob
        InfectedSpinner_2Label          matlab.ui.control.Label
        InfectedSpinner_2               matlab.ui.control.Spinner
        RecoveredSpinner_2Label         matlab.ui.control.Label
        RecoveredSpinner_2              matlab.ui.control.Spinner
        DeadSpinner_2Label              matlab.ui.control.Label
        DeadSpinner_2                   matlab.ui.control.Spinner
        HospitalizationSpinner_2Label   matlab.ui.control.Label
        HospitalizationSpinner_2        matlab.ui.control.Spinner
        FuneralSpinner_2Label           matlab.ui.control.Label
        FuneralSpinner_2                matlab.ui.control.Spinner
    end

    methods (Access = private)

        % Button pushed function: FINISHButton
        function FINISHButtonPushed(app, event)
            if(app.SEIRButton.Value)
                app.UIAxes.cla;      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E I I R]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),5)))];
                
            end 
            
            if(app.SEIHRButton.Value)
                app.UIAxes.cla        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-2*H;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E I I H H R]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.HospitalizedTextArea.Value = [mat2str(round(xa(length(tspan),5)+xa(length(tspan),6)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),7)))];

            end 
            
            if(app.SEIRDButton.Value)
                app.UIAxes.cla     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-D;                 
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E I I R D]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),5)))];
                app.DeadTextArea.Value = [mat2str(round(xa(length(tspan),6)))];
            end 
            
            if(app.SEIHRDButton.Value)
                app.UIAxes.cla      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-2*H-D;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E I I H H R D]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');    
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.HospitalizedTextArea.Value = [mat2str(round(xa(length(tspan),5)+xa(length(tspan),6)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),7)))];
                app.DeadTextArea.Value = [mat2str(round(xa(length(tspan),8)))];
            end 
            
            if(app.SEIHRFDButton.Value)
                app.UIAxes.cla   
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-2*H-D-F;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;
                
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E I I H H R F D]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.HospitalizedTextArea.Value = [mat2str(round(xa(length(tspan),5)+xa(length(tspan),6)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),7)))];
                app.FuneralTextArea.Value = [mat2str(round(xa(length(tspan),8)))];
                app.DeadTextArea.Value = [mat2str(round(xa(length(tspan),9)))];            
            end 
                
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.UIAxes.cla
            
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);  
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.SEIRButton.Value)
                app.UIAxes.cla     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E I I R]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),5)))];
            end 
            
            if(app.SEIHRButton.Value)
                app.UIAxes.cla       
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-2*H;                 
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E I I H H R]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');

                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.HospitalizedTextArea.Value = [mat2str(round(xa(length(tspan),5)+xa(length(tspan),6)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),7)))];
            end 
            
            if(app.SEIRDButton.Value)
                app.UIAxes.cla     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-D;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E I I R D]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),5)))];
                app.DeadTextArea.Value = [mat2str(round(xa(length(tspan),6)))];
            end 
            
            if(app.SEIHRDButton.Value)
                app.UIAxes.cla        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-2*H-D;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E I I H H R D]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');      
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.HospitalizedTextArea.Value = [mat2str(round(xa(length(tspan),5)+xa(length(tspan),6)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),7)))];
                app.DeadTextArea.Value = [mat2str(round(xa(length(tspan),8)))];

            end 
            
            if(app.SEIHRFDButton.Value)
                app.UIAxes.cla      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                I    = 0.5*app.InfectedSpinner.Value;
                H    = 0.5*app.HospitalizationSpinner.Value;
                R    = app.RecoveredSpinner.Value;
                F    = app.FuneralSpinner.Value
                D    = app.DeadSpinner.Value;
                S    = N-E-2*I-R-2*H-D-F;                
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;
                
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E I I H H R F D]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(round(xa(length(tspan),1)))];
                app.ExposedTextArea.Value = [mat2str(round(xa(length(tspan),2)))];
                app.InfectedTextArea.Value = [mat2str(round(xa(length(tspan),3)+xa(length(tspan),4)))];
                app.HospitalizedTextArea.Value = [mat2str(round(xa(length(tspan),5)+xa(length(tspan),6)))];
                app.RecoveredTextArea.Value = [mat2str(round(xa(length(tspan),7)))];
                app.FuneralTextArea.Value = [mat2str(round(xa(length(tspan),8)))];
                app.DeadTextArea.Value = [mat2str(round(xa(length(tspan),9)))];  
            end 
        end

        % Value changed function: TimedaysKnob
        function TimedaysKnobValueChanged(app, event)
            value = app.TimedaysKnob.Value;
        end

        % Value changed function: SusceptiblesTextArea
        function SusceptiblesTextAreaValueChanged(app, event)
            value = app.SusceptiblesTextArea.Value;
        end

        % Selection changed function: CompartmentsButtonGroup
        function Compartments(app, event)
            selectedButton = app.CompartmentsButtonGroup.SelectedObject;
            
        end

        % Value changed function: NTotalPopulationSlider
        function N(app, event)
            value = app.NTotalPopulationSlider.Value;
            
        end

        % Value changed function: BetaCommunityContactRateSlider
        function Beta(app, event)
            value = app.BetaCommunityContactRateSlider.Value;
            
        end

        % Value changed function: FuneralTextArea
        function FuneralTextAreaValueChanged(app, event)
            value = app.FuneralTextArea.Value;
            
        end

        % Value changed function: InfectedTextArea
        function InfectedTextAreaValueChanged(app, event)
            value = app.InfectedTextArea.Value;
            
        end

        % Value changed function: RecoveredTextArea
        function RecoveredTextAreaValueChanged(app, event)
            value = app.RecoveredTextArea.Value;
            
        end

        % Value changed function: ExposedTextArea
        function ExposedTextAreaValueChanged(app, event)
            value = app.ExposedTextArea.Value;
            
        end

        % Value changed function: HospitalizedTextArea
        function HospitalizedTextAreaValueChanged(app, event)
            value = app.HospitalizedTextArea.Value;
            
        end

        % Value changed function: DeadTextArea
        function DeadTextAreaValueChanged(app, event)
            value = app.DeadTextArea.Value;
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            if(app.SEIRButton_2.Value)
                app.UIAxes_2.cla   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                I    = 0.5*app.InfectedSpinner_2.Value;
                H    = 0.5*app.HospitalizationSpinner_2.Value;
                R    = app.RecoveredSpinner_2.Value;
                F    = app.FuneralSpinner_2.Value
                D    = app.DeadSpinner_2.Value;
                S    = N-E-2*I-R;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E I I R]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(round(RMSE));
            end
            
            if(app.SEIHRButton_2.Value)
                app.UIAxes_2.cla   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                I    = 0.5*app.InfectedSpinner_2.Value;
                H    = 0.5*app.HospitalizationSpinner_2.Value;
                R    = app.RecoveredSpinner_2.Value;
                F    = app.FuneralSpinner_2.Value
                D    = app.DeadSpinner_2.Value;
                S    = N-E-2*I-R-2*H; 
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E I I H H R]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(round(RMSE));
            end
            
            if(app.SEIRDButton_2.Value)
                app.UIAxes_2.cla   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                I    = 0.5*app.InfectedSpinner_2.Value;
                H    = 0.5*app.HospitalizationSpinner_2.Value;
                R    = app.RecoveredSpinner_2.Value;
                F    = app.FuneralSpinner_2.Value
                D    = app.DeadSpinner_2.Value;
                S    = N-E-2*I-R-D;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E I I R D]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(round(RMSE));
            end
         
            if(app.SEIHRDButton_2.Value)
                app.UIAxes_2.cla   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                I    = 0.5*app.InfectedSpinner_2.Value;
                H    = 0.5*app.HospitalizationSpinner_2.Value;
                R    = app.RecoveredSpinner_2.Value;
                F    = app.FuneralSpinner_2.Value
                D    = app.DeadSpinner_2.Value;
                S    = N-E-2*I-R-2*H-D;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E I I H H R D]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(round(RMSE));
            end                        

            if(app.SEIHRFDButton_2.Value)
                app.UIAxes_2.cla   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                I    = 0.5*app.InfectedSpinner_2.Value;
                H    = 0.5*app.HospitalizationSpinner_2.Value;
                R    = app.RecoveredSpinner_2.Value;
                F    = app.FuneralSpinner_2.Value
                D    = app.DeadSpinner_2.Value;
                S    = N-E-2*I-R-2*H-D-F;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E I I H H R F D]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(round(RMSE));
            end                        
            
        end

        % Value changed function: NTotalPopulationSlider_2
        function NTotalPopulationSlider_2ValueChanged(app, event)
            value = app.NTotalPopulationSlider_2.Value;
            
        end

        % Value changed function: BetaCommunityContactRateSlider_2
        function BetaCommunityContactRateSlider_2ValueChanged(app, event)
            value = app.BetaCommunityContactRateSlider_2.Value;
            
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            app.UIAxes_2.cla
        end

        % Value changed function: RMSETextArea
        function RMSETextAreaValueChanged(app, event)
            value = app.RMSETextArea.Value;
            
        end

        % Value changed function: AlphaIncubationPeriodSlider
        function AlphaIncubationPeriodSliderValueChanged(app, event)
            value = app.AlphaIncubationPeriodSlider.Value;
        end

        % Value changed function: ThetaProbabilityofSurvivalSlider
        function ThetaProbabilityofSurvivalSliderValueChanged(app, event)
            value = app.ThetaProbabilityofSurvivalSlider.Value;
            
        end

        % Value changed function: 
        % PiProbabilityofSeekingHospitalizationSlider
        function PiProbabilityofSeekingHospitalizationSliderValueChanged(app, event)
            value = app.PiProbabilityofSeekingHospitalizationSlider.Value;
            
        end

        % Value changed function: e1TimefromInfectiontoRecoverySlider
        function e1TimefromInfectiontoRecoverySliderValueChanged(app, event)
            value = app.e1TimefromInfectiontoRecoverySlider.Value;
            
        end

        % Value changed function: e2k2TimeuntilHospitalizationSlider
        function e2k2TimeuntilHospitalizationSliderValueChanged(app, event)
            value = app.e2k2TimeuntilHospitalizationSlider.Value;
            
        end

        % Value changed function: k1TimefromInfectiontoDeathSlider
        function k1TimefromInfectiontoDeathSliderValueChanged(app, event)
            value = app.k1TimefromInfectiontoDeathSlider.Value;
            
        end

        % Value changed function: BetaHHospitalContactRateSlider
        function BetaHHospitalContactRateSliderValueChanged(app, event)
            value = app.BetaHHospitalContactRateSlider.Value;
            
        end

        % Value changing function: 
        % RoeTimefromHospitalizationtoRecoverySlider
        function RoeTimefromHospitalizationtoRecoverySliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: 
        % RoeTimefromHospitalizationtoRecoverySlider
        function RoeTimefromHospitalizationtoRecoverySliderValueChanged(app, event)
            value = app.RoeTimefromHospitalizationtoRecoverySlider.Value;
            
        end

        % Value changed function: 
        % DeltaTimefromHospitalizationtoDeathSlider
        function DeltaTimefromHospitalizationtoDeathSliderValueChanged(app, event)
            value = app.DeltaTimefromHospitalizationtoDeathSlider.Value;
            
        end

        % Value changed function: GammaDurationofBurialTimeSlider
        function GammaDurationofBurialTimeSliderValueChanged(app, event)
            value = app.GammaDurationofBurialTimeSlider.Value;
            
        end

        % Value changed function: BetaFFuneralContactRateSlider
        function BetaFFuneralContactRateSliderValueChanged(app, event)
            value = app.BetaFFuneralContactRateSlider.Value;
            
        end

        % Value changed function: EKnob
        function EKnobValueChanged(app, event)
            value = app.EKnob.Value;
            
        end

        % Value changed function: AlphaIncubationPeriodSlider_2
        function AlphaIncubationPeriodSlider_2ValueChanged(app, event)
            value = app.AlphaIncubationPeriodSlider_2.Value;
            
        end

        % Value changed function: ThetaProbabilityofSurvivalSlider_2
        function ThetaProbabilityofSurvivalSlider_2ValueChanged(app, event)
            value = app.ThetaProbabilityofSurvivalSlider_2.Value;
            
        end

        % Value changed function: 
        % PiProbabilityofSeekingHospitalizationSlider_2
        function PiProbabilityofSeekingHospitalizationSlider_2ValueChanged(app, event)
            value = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
            
        end

        % Value changed function: 
        % e1TimefromInfectiontoRecoverySlider_2
        function e1TimefromInfectiontoRecoverySlider_2ValueChanged(app, event)
            value = app.e1TimefromInfectiontoRecoverySlider_2.Value;
            
        end

        % Value changed function: e2k2TimeuntilHospitalizationSlider_2
        function e2k2TimeuntilHospitalizationSlider_2ValueChanged(app, event)
            value = app.e2k2TimeuntilHospitalizationSlider_2.Value;
            
        end

        % Value changed function: k1TimefromInfectiontoDeathSlider_2
        function k1TimefromInfectiontoDeathSlider_2ValueChanged(app, event)
            value = app.k1TimefromInfectiontoDeathSlider_2.Value;
            
        end

        % Value changed function: BetaHHospitalContactRateSlider_2
        function BetaHHospitalContactRateSlider_2ValueChanged(app, event)
            value = app.BetaHHospitalContactRateSlider_2.Value;
            
        end

        % Value changed function: 
        % RoeTimefromHospitalizationtoRecoverySlider_2
        function RoeTimefromHospitalizationtoRecoverySlider_2ValueChanged(app, event)
            value = app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
            
        end

        % Value changed function: 
        % DeltaTimefromHospitalizationtoDeathSlider_2
        function DeltaTimefromHospitalizationtoDeathSlider_2ValueChanged(app, event)
            value = app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
            
        end

        % Value changed function: GammaDurationofBurialTimeSlider_2
        function GammaDurationofBurialTimeSlider_2ValueChanged(app, event)
            value = app.GammaDurationofBurialTimeSlider_2.Value;
            
        end

        % Value changed function: BetaFFuneralContactRateSlider_2
        function BetaFFuneralContactRateSlider_2ValueChanged(app, event)
            value = app.BetaFFuneralContactRateSlider_2.Value;
            
        end

        % Value changed function: EKnob_2
        function EKnob_2ValueChanged(app, event)
            value = app.EKnob_2.Value;
            
        end

        % Button pushed function: STARTButton_3
        function STARTButton_3Pushed(app, event)
        app.UIAxes_3.cla;
        hold(app.UIAxes_3, 'on');
        width = round(app.BetaCommunityContactRateSlider_4.Value);
        height = round(app.BetaCommunityContactRateSlider_4.Value); %define area
        tEnd = round(app.TimedaysKnob_2.Value); %time to run
        I = round(app.IKnob.Value); %# of infected
        N = round(app.NTotalPopulationSlider_3.Value); %# number of agents
        R = round(app.RKnob.Value); % number of recovered
        xs = zeros(N,1); %x positions
        ys = zeros(N,1); %y positions
        ixs = ones(N,1)*-1; %infected x positions
        iys = ones(N,1)*-1; %infected y positions
        rxs = ones(N,1)*-1; 
        rys = ones(N,1)*-1; 
        pM = app.PMovementSpinner.Value; %probability of movement
        pT = app.PTransmitSpinner.Value; %probability of transmission
        pDU = app.PDeathUSpinner.Value; %probability of death (unhospitalized)
        pDH = app.PDeathHSpinner.Value; %probability of death (hospitalized) 
        pH = app.PSeekingHSpinner.Value; %probability of seeking hospitalization 
        pF = (1-pH)*pDU ; %probability of funeral, and by extension death
        sick = [];
        dead = [];
        recovered = [];
        hospitalized = [];
        funeral = [];
        healthy = [];
        
        tic
        
        for count = 1:N %initializes the structure definitions of each agent
            population(count).name = count; %gives a label
            population(count).x = round(rand(1,1)*width); %defines x positions randomly
            population(count).y = round(rand(1,1)*height); %defines y position randomly
            population(count).incubation = 0; %defines every agent as healthy
            xs(population(count).name) = population(count).x; %sets the xs value to the x position
            ys(population(count).name) = population(count).y; %sets the ys value to the y position
        end
        plot(app.UIAxes_3,xs,ys,'.','markersize',20); %starts plot of everyone
        legend(app.UIAxes_3,'Susceptible','Infected','Recovered');
        xlim(app.UIAxes_3, [-5 width+5]);
        ylim(app.UIAxes_3, [-5 height+5]);
        for count = 1:I
            population(count).incubation = floor(rand(1,1)*28+2); %sets first x population to be sick
        end
        hold(app.UIAxes_3, 'on');
        ixs(1:I) = xs(1:I); %defines sick xs
        iys(1:I) = ys(1:I); %defines sick ys
        rxs(1:R) = xs((N-R+1):N); %defines sick xs
        rys(1:R) = ys((N-R+1):N); %defines sick ys
        plot(app.UIAxes_3,ixs(1:I)>0,iys(1:I)>0,'r.','markersize',20) %plots sick
        plot(app.UIAxes_3,rxs(1:R)>0,rys(1:R)>0,'g.','markersize',20) %plots recovered
        
        legend(app.UIAxes_3,'Susceptible','Infected','Recovered');
        xlim(app.UIAxes_3, [-5 width+5]);
        ylim(app.UIAxes_3, [-5 height+5]);
        for t = 1:tEnd %loops for time
            for count = 1:N %loops per agent
                m = rand(1,1); %chance to move
                if m<pM %determines if moves
                    m = floor(rand(1,1)*8+1); %determines direction
                    %m = 8;
                    switch m %goes into case based on direction
                        case 1
                            if population(count).x < width
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            else
                                population(count).x = population(count).x - 1;
                                xs(count) = population(count).x;
                            end
                        case 2 %example of down and to the right
                            if (population(count).x < width)&&(population(count).y > 0) %checks if not at either bound
                                population(count).x = population(count).x + 1; %moves the x
                                population(count).y = population(count).y - 1; %moves the y
                                xs(count) = population(count).x; %sets xs
                                ys(count) = population(count).y; %sets ys
                            elseif population(count).x < width %checks if it is at just the y bound
                                population(count).y = population(count).y + 1; %bounces the opposite direction
                                ys(count) = population(count).y; %sets ys
                            else
                                population(count).x = population(count).x - 1; %bounces x
                                xs(count) = population(count).x; %sets x
                            end
                        case 3
                            if population(count).y > 0
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            else
                                population(count).y = population(count).y + 1;
                                ys(count) = population(count).y;
                            end
                        case 4
                            if (population(count).x > 0)&&(population(count).y > 0)
                                population(count).x = population(count).x - 1;
                                population(count).y = population(count).y - 1;
                                xs(count) = population(count).x;
                                ys(count) = population(count).y;
                            elseif population(count).x > 0
                                population(count).y = population(count).y + 1;
                                ys(count) = population(count).y;
                            else
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            end
                        case 5
                            if population(count).x > 0
                                population(count).x = population(count).x - 1;
                                xs(count) = population(count).x;
                            else
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            end
                        case 6
                            if (population(count).x > 0)&&(population(count).y < height)
                                population(count).x = population(count).x - 1;
                                population(count).y = population(count).y + 1;
                                xs(count) = population(count).x;
                                ys(count) = population(count).y;
                            elseif population(count).x > 0
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            else
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            end
                        case 7
                            if population(count).y < height
                                population(count).y = population(count).y + 1;
                                ys(count) = population(count).y;
                            else
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            end
                        case 8
                            if (population(count).x < width)&&(population(count).y < height)
                                population(count).x = population(count).x + 1;
                                population(count).y = population(count).y + 1;
                                xs(count) = population(count).x;
                                ys(count) = population(count).y;
                            elseif population(count).x < width
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            else
                                population(count).x = population(count).x - 1;
                                xs(count) = population(count).x;
                            end
                    end
                end
            end
            app.UIAxes_3.cla;
            hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3,xs,ys,'.','markersize',20) %plots health
            legend(app.UIAxes_3,'Susceptible','Infected','Recovered');
            ixs(1:I) = xs(1:I); %defines sick xs
            iys(1:I) = ys(1:I); %defines sick ys
            rxs(1:R) = xs((N-R+1):N); %defines sick xs
            rys(1:R) = ys((N-R+1):N); %defines sick ys
            xlim(app.UIAxes_3, [-5 width+5]);
            ylim(app.UIAxes_3, [-5 height+5]);
            for count = 1:N %loops per agent
                for pos = 1:N %loops per sick
                    if (xs(count) == ixs(pos))&&(ys(count)==iys(pos))&&population(count).incubation <1 %checks if positions overlap
                        T = rand(1,1); %random to pass on disease
                        if T<pT %determines if disease passes
                            population(count).incubation = round(rand(1,1)*19+2); %sets incubation
                            rxs(1:sum(ixs == -3)) = xs((N-sum(ixs == -3)+1):N); %defines sick xs
                            rys(1:sum(ixs == -3)) = ys((N-sum(ixs == -3)+1):N); %defines sick ys
                        end
                    end
                end
                   
            end
            for count = 1:N
               if population(count).incubation>0 %checks if infected
                   ixs(count) = xs(count); %adds to infected pool
                   iys(count) = ys(count);
               else
                   ixs(count) = -1; %removes from infected pool if not infected
                   iys(count) = -1;
               end
               for state = 1:N
                   D = rand(1,1);
                   if (D<pDU) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       ixs(count) = -2;
                   elseif (D>pDU) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       rxs(count) = ixs(count); 
                       rys(count) = iys(count); 
                       ixs(count) = -3;
                   end       
                   if (D<pDH) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       ixs(count) = -2;
                   elseif (D>pDH) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       rxs(count) = ixs(count); 
                       rys(count) = iys(count); 
                       ixs(count) = -3;
                   end                
                   H = rand(1,1);  
                   if (H<pH) && (population(count).incubation == 15)
                       ixs(count) = -4;
                   end
                   F = rand(1,1);
                   if (F<pF) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       ixs(count) = -5;
                   end
               end
               population(count).incubation = population(count).incubation -1; %ticks down incubation
            end
            hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3,ixs(ixs>0),iys(ixs>0),'r.','markersize',20) %plots again
            plot(app.UIAxes_3,rxs(rxs>0),rys(rxs>0),'g.','markersize',20) %plots again
            legend(app.UIAxes_3,'Susceptible','Infected','Recovered');
            xlim(app.UIAxes_3, [-5 width+5]);
            ylim(app.UIAxes_3, [-5 height+5]);
            pause(0.1); %waits for display sake
      
            sick = [sick ; sum(ixs>0)]; %counts number of sick
            dead = [dead ; sum(ixs == -2)+sum(ixs == -5)];
            recovered = [recovered ; sum(ixs == -3)];
            hospitalized = [hospitalized ; sum(ixs == -4)];
            funeral = [funeral ; sum(ixs == -5)];
            healthy = [healthy ; N-sick(t)-dead(t)+recovered(t)-hospitalized(t)-funeral(t)]; %counts number of healthy
            app.SusceptibleTextArea.Value = num2str(healthy(t));
            app.InfectedTextArea_2.Value  = num2str(sick(t));
            app.RecoveredTextArea_2.Value = num2str(recovered(t)+R);
            
            
            app.Knob.Value                = round(t/tEnd*100);
        end
        
        toc    
        
        hold(app.UIAxes_4, 'on');
        plot(app.UIAxes_4,1:tEnd,healthy,1:tEnd,sick,1:tEnd,dead,1:tEnd,recovered,1:tEnd,hospitalized,1:tEnd,funeral,'LineWidth',3);
        %plot(app.UIAxes_4,1:tEnd,cumsum(sick),'LineWidth',3);
        legend(app.UIAxes_4,'Susceptible','Infected','Dead','Recovered','Hospitalized','Funeral');
        %legend(app.UIAxes_4,'Susceptible','Infected','Dead','Recovered','Hospitalized','Funeral','Total Cases');
        
        sick_cum = cumsum(sick);
        recovered_cum = cumsum(recovered);
        dead_cum = cumsum(dead);
        hospitalized_cum = cumsum(hospitalized);
        funeral_cum = cumsum(funeral);
        
        app.InfectedTextArea_3.Value     = num2str(round(sick_cum(tEnd)*app.EffectivePopSpinner.Value/N));
        app.RecoveredTextArea_3.Value    = num2str(round(recovered_cum(tEnd)*app.EffectivePopSpinner.Value/N));
        app.DeadTextArea_2.Value         = num2str(round(dead_cum(tEnd)*app.EffectivePopSpinner.Value/N));
        app.HospitalizedTextArea_2.Value = num2str(round(hospitalized_cum(tEnd)*app.EffectivePopSpinner.Value/N));
        app.FuneralTextArea_2.Value      = num2str(round(funeral_cum(tEnd)*app.EffectivePopSpinner.Value/N));       

        
        end

        % Button pushed function: CLEARButton_3
        function CLEARButton_3Pushed(app, event)
            app.UIAxes_3.cla;
            app.UIAxes_4.cla;
            app.SusceptibleTextArea.Value = num2str(0);
            app.InfectedTextArea_2.Value  = num2str(0);
            app.RecoveredTextArea_2.Value = num2str(0);  
            app.Knob.Value                = 0;        
            app.InfectedTextArea_3.Value     = num2str(0);
            app.RecoveredTextArea_3.Value    = num2str(0);
            app.DeadTextArea_2.Value         = num2str(0);
            app.HospitalizedTextArea_2.Value = num2str(0);
            app.FuneralTextArea_2.Value      = num2str(0);            
        end

        % Value changed function: NTotalPopulationSlider_3
        function NTotalPopulationSlider_3ValueChanged(app, event)
            value = app.NTotalPopulationSlider_3.Value;
            
        end

        % Value changed function: BetaCommunityContactRateSlider_4
        function BetaCommunityContactRateSlider_4ValueChanged(app, event)
            value = app.BetaCommunityContactRateSlider_4.Value;
            
        end

        % Value changed function: TimedaysKnob_2
        function TimedaysKnob_2ValueChanged(app, event)
            value = app.TimedaysKnob_2.Value;
            
        end

        % Value changed function: IKnob
        function IKnobValueChanged(app, event)
            value = app.IKnob.Value;
            
        end

        % Value changed function: RKnob
        function RKnobValueChanged(app, event)
            value = app.RKnob.Value;
            
        end

        % Value changed function: PMovementSpinner
        function PMovementSpinnerValueChanged(app, event)
            value = app.PMovementSpinner.Value;
            
        end

        % Value changed function: PTransmitSpinner
        function PTransmitSpinnerValueChanged(app, event)
            value = app.PTransmitSpinner.Value;
            
        end

        % Value changed function: PDeathUSpinner
        function PDeathUSpinnerValueChanged(app, event)
            value = app.PDeathUSpinner.Value;
            
        end

        % Value changed function: PDeathHSpinner
        function PDeathHSpinnerValueChanged(app, event)
            value = app.PDeathHSpinner.Value;
            
        end

        % Value changed function: PSeekingHSpinner
        function PSeekingHSpinnerValueChanged(app, event)
            value = app.PSeekingHSpinner.Value;
            
        end

        % Value changed function: SusceptibleTextArea
        function SusceptibleTextAreaValueChanged(app, event)
            value = app.SusceptibleTextArea.Value;
            
        end

        % Value changed function: InfectedTextArea_2
        function InfectedTextArea_2ValueChanged(app, event)
            value = app.InfectedTextArea_2.Value;
            
        end

        % Value changed function: RecoveredTextArea_2
        function RecoveredTextArea_2ValueChanged(app, event)
            value = app.RecoveredTextArea_2.Value;
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
        end

        % Size changed function: DPanel
        function DPanelSizeChanged(app, event)
            position = app.DPanel.Position;
            
        end

        % Callback function
        function EXPORTCOMPARTMENTSButtonPushed(app, event)
            filename = 'output1.xlsx'
            xlswrite(filename, healthy, sick, recovered, dead, hospitalized, funeral);
        end

        % Value changed function: InfectedTextArea_3
        function InfectedTextArea_3ValueChanged(app, event)
            value = app.InfectedTextArea_3.Value;
            
        end

        % Value changed function: RecoveredTextArea_3
        function RecoveredTextArea_3ValueChanged(app, event)
            value = app.RecoveredTextArea_3.Value;
            
        end

        % Value changed function: DeadTextArea_2
        function DeadTextArea_2ValueChanged(app, event)
            value = app.DeadTextArea_2.Value;
            
        end

        % Value changed function: HospitalizedTextArea_2
        function HospitalizedTextArea_2ValueChanged(app, event)
            value = app.HospitalizedTextArea_2.Value;
            
        end

        % Value changed function: FuneralTextArea_2
        function FuneralTextArea_2ValueChanged(app, event)
            value = app.FuneralTextArea_2.Value;
            
        end

        % Value changed function: EffectivePopSpinner
        function EffectivePopSpinnerValueChanged(app, event)
            value = app.EffectivePopSpinner.Value;
            
        end

        % Value changed function: InfectedSpinner
        function InfectedSpinnerValueChanged(app, event)
            value = app.InfectedSpinner.Value;
            
        end

        % Value changed function: RecoveredSpinner
        function RecoveredSpinnerValueChanged(app, event)
            value = app.RecoveredSpinner.Value;
            
        end

        % Value changed function: DeadSpinner
        function DeadSpinnerValueChanged(app, event)
            value = app.DeadSpinner.Value;
            
        end

        % Value changed function: HospitalizationSpinner
        function HospitalizationSpinnerValueChanged(app, event)
            value = app.HospitalizationSpinner.Value;
            
        end

        % Value changed function: FuneralSpinner
        function FuneralSpinnerValueChanged(app, event)
            value = app.FuneralSpinner.Value;
            
        end

        % Value changed function: InfectedSpinner_2
        function InfectedSpinner_2ValueChanged(app, event)
            value = app.InfectedSpinner_2.Value;
            
        end

        % Value changed function: RecoveredSpinner_2
        function RecoveredSpinner_2ValueChanged(app, event)
            value = app.RecoveredSpinner_2.Value;
            
        end

        % Value changed function: DeadSpinner_2
        function DeadSpinner_2ValueChanged(app, event)
            value = app.DeadSpinner_2.Value;
            
        end

        % Value changed function: HospitalizationSpinner_2
        function HospitalizationSpinner_2ValueChanged(app, event)
            value = app.HospitalizationSpinner_2.Value;
            
        end

        % Value changed function: FuneralSpinner_2
        function FuneralSpinner_2ValueChanged(app, event)
            value = app.FuneralSpinner_2.Value;
            
        end

        % Value changed function: NTotalPopulationSlider_4
        function NTotalPopulationSlider_4ValueChanged(app, event)
            value = app.NTotalPopulationSlider_4.Value;
            
        end

        % Button pushed function: STARTButton_4
        function STARTButton_4Pushed(app, event)
        app.UIAxes_5.cla;
        hold(app.UIAxes_5, 'on');
        width = round(app.BetaCommunityContactRateSlider_5.Value);
        height = round(app.BetaCommunityContactRateSlider_5.Value); %define area
        tEnd = round(app.TimedaysKnob_3.Value); %time to run
        I = round(app.IKnob_2.Value); %# of infected
        N = round(app.NTotalPopulationSlider_4.Value); %# number of agents
        R = round(app.RKnob_2.Value); % number of recovered
        xs = zeros(N,1); %x positions
        ys = zeros(N,1); %y positions
        ixs = ones(N,1)*-1; %infected x positions
        iys = ones(N,1)*-1; %infected y positions
        rxs = ones(N,1)*-1; 
        rys = ones(N,1)*-1; 
        pM = app.PMovementSpinner_2.Value; %probability of movement
        pT = app.PTransmitSpinner_2.Value; %probability of transmission
        pDU = app.PDeathUSpinner_2.Value; %probability of death (unhospitalized)
        pDH = app.PDeathHSpinner_2.Value; %probability of death (hospitalized) 
        pH = app.PSeekingHSpinner_2.Value; %probability of seeking hospitalization 
        pF = (1-pH)*pDU ; %probability of funeral, and by extension death
        sick = [];
        dead = [];
        recovered = [];
        hospitalized = [];
        funeral = [];
        healthy = [];
        
        [filename, filepath, ~] = uigetfile('*.xlsx');
        [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
        tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
        I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
        D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));        
        
        tic
        
        for count = 1:N %initializes the structure definitions of each agent
            population(count).name = count; %gives a label
            population(count).x = round(rand(1,1)*width); %defines x positions randomly
            population(count).y = round(rand(1,1)*height); %defines y position randomly
            population(count).incubation = 0; %defines every agent as healthy
            xs(population(count).name) = population(count).x; %sets the xs value to the x position
            ys(population(count).name) = population(count).y; %sets the ys value to the y position
        end
        plot(app.UIAxes_5,xs,ys,'.','markersize',20); %starts plot of everyone
        legend(app.UIAxes_5,'Susceptible','Infected','Recovered');
        xlim(app.UIAxes_5, [-5 width+5]);
        ylim(app.UIAxes_5, [-5 height+5]);
        for count = 1:I
            population(count).incubation = floor(rand(1,1)*28+2); %sets first x population to be sick
        end
        hold(app.UIAxes_5, 'on');
        ixs(1:I) = xs(1:I); %defines sick xs
        iys(1:I) = ys(1:I); %defines sick ys
        rxs(1:R) = xs((N-R+1):N); %defines sick xs
        rys(1:R) = ys((N-R+1):N); %defines sick ys
        plot(app.UIAxes_5,ixs(1:I)>0,iys(1:I)>0,'r.','markersize',20) %plots sick
        plot(app.UIAxes_5,rxs(1:R)>0,rys(1:R)>0,'g.','markersize',20) %plots recovered
        
        legend(app.UIAxes_5,'Susceptible','Infected','Recovered');
        xlim(app.UIAxes_5, [-5 width+5]);
        ylim(app.UIAxes_5, [-5 height+5]);
        for t = 1:tEnd %loops for time
            for count = 1:N %loops per agent
                m = rand(1,1); %chance to move
                if m<pM %determines if moves
                    m = floor(rand(1,1)*8+1); %determines direction
                    %m = 8;
                    switch m %goes into case based on direction
                        case 1
                            if population(count).x < width
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            else
                                population(count).x = population(count).x - 1;
                                xs(count) = population(count).x;
                            end
                        case 2 %example of down and to the right
                            if (population(count).x < width)&&(population(count).y > 0) %checks if not at either bound
                                population(count).x = population(count).x + 1; %moves the x
                                population(count).y = population(count).y - 1; %moves the y
                                xs(count) = population(count).x; %sets xs
                                ys(count) = population(count).y; %sets ys
                            elseif population(count).x < width %checks if it is at just the y bound
                                population(count).y = population(count).y + 1; %bounces the opposite direction
                                ys(count) = population(count).y; %sets ys
                            else
                                population(count).x = population(count).x - 1; %bounces x
                                xs(count) = population(count).x; %sets x
                            end
                        case 3
                            if population(count).y > 0
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            else
                                population(count).y = population(count).y + 1;
                                ys(count) = population(count).y;
                            end
                        case 4
                            if (population(count).x > 0)&&(population(count).y > 0)
                                population(count).x = population(count).x - 1;
                                population(count).y = population(count).y - 1;
                                xs(count) = population(count).x;
                                ys(count) = population(count).y;
                            elseif population(count).x > 0
                                population(count).y = population(count).y + 1;
                                ys(count) = population(count).y;
                            else
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            end
                        case 5
                            if population(count).x > 0
                                population(count).x = population(count).x - 1;
                                xs(count) = population(count).x;
                            else
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            end
                        case 6
                            if (population(count).x > 0)&&(population(count).y < height)
                                population(count).x = population(count).x - 1;
                                population(count).y = population(count).y + 1;
                                xs(count) = population(count).x;
                                ys(count) = population(count).y;
                            elseif population(count).x > 0
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            else
                                population(count).x = population(count).x + 1;
                                xs(count) = population(count).x;
                            end
                        case 7
                            if population(count).y < height
                                population(count).y = population(count).y + 1;
                                ys(count) = population(count).y;
                            else
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            end
                        case 8
                            if (population(count).x < width)&&(population(count).y < height)
                                population(count).x = population(count).x + 1;
                                population(count).y = population(count).y + 1;
                                xs(count) = population(count).x;
                                ys(count) = population(count).y;
                            elseif population(count).x < width
                                population(count).y = population(count).y - 1;
                                ys(count) = population(count).y;
                            else
                                population(count).x = population(count).x - 1;
                                xs(count) = population(count).x;
                            end
                    end
                end
            end
            app.UIAxes_5.cla;
            hold(app.UIAxes_5, 'on');
            plot(app.UIAxes_5,xs,ys,'.','markersize',20) %plots health
            legend(app.UIAxes_5,'Susceptible','Infected','Recovered');
            ixs(1:I) = xs(1:I); %defines sick xs
            iys(1:I) = ys(1:I); %defines sick ys
            rxs(1:R) = xs((N-R+1):N); %defines sick xs
            rys(1:R) = ys((N-R+1):N); %defines sick ys
            xlim(app.UIAxes_5, [-5 width+5]);
            ylim(app.UIAxes_5, [-5 height+5]);
            for count = 1:N %loops per agent
                for pos = 1:N %loops per sick
                    if (xs(count) == ixs(pos))&&(ys(count)==iys(pos))&&population(count).incubation <1 %checks if positions overlap
                        T = rand(1,1); %random to pass on disease
                        if T<pT %determines if disease passes
                            population(count).incubation = round(rand(1,1)*19+2); %sets incubation
                            rxs(1:sum(ixs == -3)) = xs((N-sum(ixs == -3)+1):N); %defines sick xs
                            rys(1:sum(ixs == -3)) = ys((N-sum(ixs == -3)+1):N); %defines sick ys
                        end
                    end
                end
                   
            end
            for count = 1:N
               if population(count).incubation>0 %checks if infected
                   ixs(count) = xs(count); %adds to infected pool
                   iys(count) = ys(count);
               else
                   ixs(count) = -1; %removes from infected pool if not infected
                   iys(count) = -1;
               end
               for state = 1:N
                   D = rand(1,1);
                   if (D<pDU) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       ixs(count) = -2;
                   elseif (D>pDU) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       rxs(count) = ixs(count); 
                       rys(count) = iys(count); 
                       ixs(count) = -3;
                   end       
                   if (D<pDH) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       ixs(count) = -2;
                   elseif (D>pDH) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       rxs(count) = ixs(count); 
                       rys(count) = iys(count); 
                       ixs(count) = -3;
                   end                
                   H = rand(1,1);  
                   if (H<pH) && (population(count).incubation == 15)
                       ixs(count) = -4;
                   end
                   F = rand(1,1);
                   if (F<pF) && (population(count).incubation > 0) && (population(count).incubation < 5)
                       ixs(count) = -5;
                   end
               end
               population(count).incubation = population(count).incubation -1; %ticks down incubation
            end
            hold(app.UIAxes_5, 'on');
            plot(app.UIAxes_5,ixs(ixs>0),iys(ixs>0),'r.','markersize',20) %plots again
            plot(app.UIAxes_5,rxs(rxs>0),rys(rxs>0),'g.','markersize',20) %plots again
            legend(app.UIAxes_5,'Susceptible','Infected','Recovered');
            xlim(app.UIAxes_5, [-5 width+5]);
            ylim(app.UIAxes_5, [-5 height+5]);
            pause(0.1); %waits for display sake
      
            sick = [sick ; sum(ixs>0)]; %counts number of sick
            dead = [dead ; sum(ixs == -2)+sum(ixs == -5)];
            recovered = [recovered ; sum(ixs == -3)];
            hospitalized = [hospitalized ; sum(ixs == -4)];
            funeral = [funeral ; sum(ixs == -5)];
            healthy = [healthy ; N-sick(t)-dead(t)+recovered(t)-hospitalized(t)-funeral(t)]; %counts number of healthy
            app.SusceptibleTextArea_2.Value = num2str(healthy(t));
            app.InfectedTextArea_4.Value  = num2str(sick(t));
            app.RecoveredTextArea_4.Value = num2str(recovered(t)+R);
            
            app.Knob_2.Value                = round(t/tEnd*100);
        end
        
        toc    
        
        for i = 1:length(tt)
            xaa(i) = transpose(sick(tt(i)))
        end        
        I_eqn = cumsum(xaa);
        
        hold(app.UIAxes_6, 'on');
        plot(app.UIAxes_6,tt,I_eqn,'LineWidth',3);
        scatter(app.UIAxes_6,tt,I_real);
        legend(app.UIAxes_6, 'Model','CDC Data');
        hold(app.UIAxes_6, 'off');
                
        RMSE = (immse(I_real, I_eqn))^0.5;
        app.RMSETextArea_2.Value = num2str(round(RMSE));
         
            
        end

        % Value changed function: BetaCommunityContactRateSlider_5
        function BetaCommunityContactRateSlider_5ValueChanged(app, event)
            value = app.BetaCommunityContactRateSlider_5.Value;
            
        end

        % Value changed function: TimedaysKnob_3
        function TimedaysKnob_3ValueChanged(app, event)
            value = app.TimedaysKnob_3.Value;
            
        end

        % Value changed function: IKnob_2
        function IKnob_2ValueChanged(app, event)
            value = app.IKnob_2.Value;
            
        end

        % Value changed function: RKnob_2
        function RKnob_2ValueChanged(app, event)
            value = app.RKnob_2.Value;
            
        end

        % Value changed function: PMovementSpinner_2
        function PMovementSpinner_2ValueChanged(app, event)
            value = app.PMovementSpinner_2.Value;
            
        end

        % Value changed function: PTransmitSpinner_2
        function PTransmitSpinner_2ValueChanged(app, event)
            value = app.PTransmitSpinner_2.Value;
            
        end

        % Value changed function: PDeathUSpinner_2
        function PDeathUSpinner_2ValueChanged(app, event)
            value = app.PDeathUSpinner_2.Value;
            
        end

        % Value changed function: PDeathHSpinner_2
        function PDeathHSpinner_2ValueChanged(app, event)
            value = app.PDeathHSpinner_2.Value;
            
        end

        % Value changed function: PSeekingHSpinner_2
        function PSeekingHSpinner_2ValueChanged(app, event)
            value = app.PSeekingHSpinner_2.Value;
            
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = app.Knob_2.Value;
            
        end

        % Value changed function: SusceptibleTextArea_2
        function SusceptibleTextArea_2ValueChanged(app, event)
            value = app.SusceptibleTextArea_2.Value;
            
        end

        % Value changed function: InfectedTextArea_4
        function InfectedTextArea_4ValueChanged(app, event)
            value = app.InfectedTextArea_4.Value;
            
        end

        % Value changed function: RecoveredTextArea_4
        function RecoveredTextArea_4ValueChanged(app, event)
            value = app.RecoveredTextArea_4.Value;
            
        end

        % Value changed function: RMSETextArea_2
        function RMSETextArea_2ValueChanged(app, event)
            value = app.RMSETextArea_2.Value;
            
        end

        % Button pushed function: CLEARButton_4
        function CLEARButton_4Pushed(app, event)
            app.UIAxes_5.cla;
            app.UIAxes_6.cla;
            app.SusceptibleTextArea_2.Value = num2str(0);
            app.InfectedTextArea_4.Value  = num2str(0);
            app.RecoveredTextArea_4.Value = num2str(0);  
            app.Knob_2.Value                = 0;        
            app.RMSETextArea_2.Value      = num2str(0);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AgentbasedModelTab = uitab(app.TabGroup);
            app.AgentbasedModelTab.Title = 'Agent-based Model';
            app.MODELDIMENSIONSLabel = uilabel(app.AgentbasedModelTab);
            app.MODELDIMENSIONSLabel.Text = 'MODEL DIMENSIONS';
            app.NTotalPopulationSlider_3 = uislider(app.AgentbasedModelTab);
            app.NTotalPopulationSlider_3.Limits = [0 1000];
            app.NTotalPopulationSlider_3.ValueChangedFcn = createCallbackFcn(app, @NTotalPopulationSlider_3ValueChanged, true);
            app.NTotalPopulationSlider_3.Value = 200;
            app.NTotalPopulationSlider_2Label_3 = uilabel(app.AgentbasedModelTab);
            app.NTotalPopulationSlider_2Label_3.Text = 'Number of agents';
            app.BetaCommunityContactRateSlider_4 = uislider(app.AgentbasedModelTab);
            app.BetaCommunityContactRateSlider_4.MajorTicks = [0 20 40 60 80 100];
            app.BetaCommunityContactRateSlider_4.ValueChangedFcn = createCallbackFcn(app, @BetaCommunityContactRateSlider_4ValueChanged, true);
            app.BetaCommunityContactRateSlider_4.Value = 20;
            app.BetaCommunityContactRateSlider_2Label_4 = uilabel(app.AgentbasedModelTab);
            app.BetaCommunityContactRateSlider_2Label_4.Text = 'Height/Width of sample space';
            app.TimedaysKnob_2Label = uilabel(app.AgentbasedModelTab);
            app.TimedaysKnob_2Label.Text = 'Time (days)';
            app.TimedaysKnob_2 = uiknob(app.AgentbasedModelTab, 'continuous');
            app.TimedaysKnob_2.Limits = [0 250];
            app.TimedaysKnob_2.ValueChangedFcn = createCallbackFcn(app, @TimedaysKnob_2ValueChanged, true);
            app.TimedaysKnob_2.Value = 100;
            app.INPUTPARAMETERSLabel_3 = uilabel(app.AgentbasedModelTab);
            app.INPUTPARAMETERSLabel_3.Text = 'INPUT PARAMETERS';
            app.IKnobLabel = uilabel(app.AgentbasedModelTab);
            app.IKnobLabel.Text = 'I';
            app.IKnob = uiknob(app.AgentbasedModelTab, 'continuous');
            app.IKnob.Limits = [0 30];
            app.IKnob.MajorTicks = [0 5 10 15 20 25 30];
            app.IKnob.ValueChangedFcn = createCallbackFcn(app, @IKnobValueChanged, true);
            app.IKnob.Value = 1;
            app.RKnobLabel = uilabel(app.AgentbasedModelTab);
            app.RKnobLabel.Text = 'R';
            app.RKnob = uiknob(app.AgentbasedModelTab, 'continuous');
            app.RKnob.Limits = [0 10];
            app.RKnob.MajorTicks = [0 2 4 6 8 10];
            app.RKnob.ValueChangedFcn = createCallbackFcn(app, @RKnobValueChanged, true);
            app.RKnob.Value = 1;
            app.PLOTLabel_3 = uilabel(app.AgentbasedModelTab);
            app.PLOTLabel_3.Text = 'PLOT';
            app.UIAxes_3 = uiaxes(app.AgentbasedModelTab);
            title(app.UIAxes_3, 'Agent-based Model')
            xlabel(app.UIAxes_3, 'Width')
            ylabel(app.UIAxes_3, 'Height')
            app.UIAxes_3.XLim = [0 1];
            app.UIAxes_4 = uiaxes(app.AgentbasedModelTab);
            title(app.UIAxes_4, 'Compartmental Model')
            xlabel(app.UIAxes_4, 'Time (days)')
            ylabel(app.UIAxes_4, 'Number of people')
            app.CONTROLSLabel_3 = uilabel(app.AgentbasedModelTab);
            app.CONTROLSLabel_3.Text = 'CONTROLS';
            app.CLEARButton_3 = uibutton(app.AgentbasedModelTab, 'push');
            app.CLEARButton_3.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_3Pushed, true);
            app.CLEARButton_3.Text = 'CLEAR';
            app.STARTButton_3 = uibutton(app.AgentbasedModelTab, 'push');
            app.STARTButton_3.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_3Pushed, true);
            app.STARTButton_3.Text = 'START';
            app.PMovementSpinnerLabel = uilabel(app.AgentbasedModelTab);
            app.PMovementSpinnerLabel.Text = 'P - Movement';
            app.PMovementSpinner = uispinner(app.AgentbasedModelTab);
            app.PMovementSpinner.Step = 0.05;
            app.PMovementSpinner.Limits = [0 1];
            app.PMovementSpinner.ValueChangedFcn = createCallbackFcn(app, @PMovementSpinnerValueChanged, true);
            app.PMovementSpinner.Value = 1;
            app.PTransmitSpinnerLabel = uilabel(app.AgentbasedModelTab);
            app.PTransmitSpinnerLabel.Text = 'P - Transmit';
            app.PTransmitSpinner = uispinner(app.AgentbasedModelTab);
            app.PTransmitSpinner.Step = 0.05;
            app.PTransmitSpinner.Limits = [0 1];
            app.PTransmitSpinner.ValueChangedFcn = createCallbackFcn(app, @PTransmitSpinnerValueChanged, true);
            app.PTransmitSpinner.Value = 0.7;
            app.PDeathUSpinnerLabel = uilabel(app.AgentbasedModelTab);
            app.PDeathUSpinnerLabel.Text = 'P - Death (U)';
            app.PDeathUSpinner = uispinner(app.AgentbasedModelTab);
            app.PDeathUSpinner.Step = 0.05;
            app.PDeathUSpinner.Limits = [0 1];
            app.PDeathUSpinner.ValueChangedFcn = createCallbackFcn(app, @PDeathUSpinnerValueChanged, true);
            app.PDeathUSpinner.Value = 0.7;
            app.PDeathHSpinnerLabel = uilabel(app.AgentbasedModelTab);
            app.PDeathHSpinnerLabel.Text = 'P - Death (H)';
            app.PDeathHSpinner = uispinner(app.AgentbasedModelTab);
            app.PDeathHSpinner.Step = 0.05;
            app.PDeathHSpinner.Limits = [0 1];
            app.PDeathHSpinner.ValueChangedFcn = createCallbackFcn(app, @PDeathHSpinnerValueChanged, true);
            app.PDeathHSpinner.Value = 0.5;
            app.PSeekingHSpinnerLabel = uilabel(app.AgentbasedModelTab);
            app.PSeekingHSpinnerLabel.Text = 'P - Seeking H';
            app.PSeekingHSpinner = uispinner(app.AgentbasedModelTab);
            app.PSeekingHSpinner.Step = 0.05;
            app.PSeekingHSpinner.Limits = [0 1];
            app.PSeekingHSpinner.ValueChangedFcn = createCallbackFcn(app, @PSeekingHSpinnerValueChanged, true);
            app.PSeekingHSpinner.Value = 0.2;
            app.SusceptibleTextAreaLabel = uilabel(app.AgentbasedModelTab);
            app.SusceptibleTextAreaLabel.Text = 'Susceptible';
            app.SusceptibleTextArea = uitextarea(app.AgentbasedModelTab);
            app.SusceptibleTextArea.ValueChangedFcn = createCallbackFcn(app, @SusceptibleTextAreaValueChanged, true);
            app.InfectedTextArea_2Label = uilabel(app.AgentbasedModelTab);
            app.InfectedTextArea_2Label.Text = 'Infected';
            app.InfectedTextArea_2 = uitextarea(app.AgentbasedModelTab);
            app.InfectedTextArea_2.ValueChangedFcn = createCallbackFcn(app, @InfectedTextArea_2ValueChanged, true);
            app.RecoveredTextArea_2Label = uilabel(app.AgentbasedModelTab);
            app.RecoveredTextArea_2Label.Text = 'Recovered';
            app.RecoveredTextArea_2 = uitextarea(app.AgentbasedModelTab);
            app.RecoveredTextArea_2.ValueChangedFcn = createCallbackFcn(app, @RecoveredTextArea_2ValueChanged, true);
            app.Knob = uiknob(app.AgentbasedModelTab, 'continuous');
            app.Knob.MajorTicks = [0 20 40 60 80 100];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.FINALLabel = uilabel(app.AgentbasedModelTab);
            app.FINALLabel.Text = 'FINAL';
            app.CONDITIONSLabel = uilabel(app.AgentbasedModelTab);
            app.CONDITIONSLabel.Text = 'CONDITIONS';
            app.InfectedTextArea_3Label = uilabel(app.AgentbasedModelTab);
            app.InfectedTextArea_3Label.Text = 'Infected';
            app.InfectedTextArea_3 = uitextarea(app.AgentbasedModelTab);
            app.InfectedTextArea_3.ValueChangedFcn = createCallbackFcn(app, @InfectedTextArea_3ValueChanged, true);
            app.RecoveredTextArea_3Label = uilabel(app.AgentbasedModelTab);
            app.RecoveredTextArea_3Label.Text = 'Recovered';
            app.RecoveredTextArea_3 = uitextarea(app.AgentbasedModelTab);
            app.RecoveredTextArea_3.ValueChangedFcn = createCallbackFcn(app, @RecoveredTextArea_3ValueChanged, true);
            app.DeadTextArea_2Label = uilabel(app.AgentbasedModelTab);
            app.DeadTextArea_2Label.Text = 'Dead';
            app.DeadTextArea_2 = uitextarea(app.AgentbasedModelTab);
            app.DeadTextArea_2.ValueChangedFcn = createCallbackFcn(app, @DeadTextArea_2ValueChanged, true);
            app.HospitalizedTextArea_2Label = uilabel(app.AgentbasedModelTab);
            app.HospitalizedTextArea_2Label.Text = 'Hospitalized';
            app.HospitalizedTextArea_2 = uitextarea(app.AgentbasedModelTab);
            app.HospitalizedTextArea_2.ValueChangedFcn = createCallbackFcn(app, @HospitalizedTextArea_2ValueChanged, true);
            app.FuneralTextArea_2Label = uilabel(app.AgentbasedModelTab);
            app.FuneralTextArea_2Label.Text = 'Funeral';
            app.FuneralTextArea_2 = uitextarea(app.AgentbasedModelTab);
            app.FuneralTextArea_2.ValueChangedFcn = createCallbackFcn(app, @FuneralTextArea_2ValueChanged, true);
            app.EffectivePopSpinnerLabel = uilabel(app.AgentbasedModelTab);
            app.EffectivePopSpinnerLabel.Text = 'Effective Pop.';
            app.EffectivePopSpinner = uispinner(app.AgentbasedModelTab);
            app.EffectivePopSpinner.Step = 100;
            app.EffectivePopSpinner.Limits = [0 10000];
            app.EffectivePopSpinner.ValueChangedFcn = createCallbackFcn(app, @EffectivePopSpinnerValueChanged, true);
            app.EffectivePopSpinner.Value = 4000;
            app.ValidationAgentbasedTab = uitab(app.TabGroup);
            app.ValidationAgentbasedTab.Title = 'Validation -- Agent-based';
            app.MODELDIMENSIONSLabel_2 = uilabel(app.ValidationAgentbasedTab);
            app.MODELDIMENSIONSLabel_2.Text = 'MODEL DIMENSIONS';
            app.NTotalPopulationSlider_4 = uislider(app.ValidationAgentbasedTab);
            app.NTotalPopulationSlider_4.Limits = [0 1000];
            app.NTotalPopulationSlider_4.ValueChangedFcn = createCallbackFcn(app, @NTotalPopulationSlider_4ValueChanged, true);
            app.NTotalPopulationSlider_4.Value = 200;
            app.NTotalPopulationSlider_2Label_4 = uilabel(app.ValidationAgentbasedTab);
            app.NTotalPopulationSlider_2Label_4.Text = 'Number of agents';
            app.BetaCommunityContactRateSlider_5 = uislider(app.ValidationAgentbasedTab);
            app.BetaCommunityContactRateSlider_5.MajorTicks = [0 20 40 60 80 100];
            app.BetaCommunityContactRateSlider_5.ValueChangedFcn = createCallbackFcn(app, @BetaCommunityContactRateSlider_5ValueChanged, true);
            app.BetaCommunityContactRateSlider_5.Value = 20;
            app.BetaCommunityContactRateSlider_2Label_5 = uilabel(app.ValidationAgentbasedTab);
            app.BetaCommunityContactRateSlider_2Label_5.Text = 'Height/Width of sample space';
            app.INPUTPARAMETERSLabel_4 = uilabel(app.ValidationAgentbasedTab);
            app.INPUTPARAMETERSLabel_4.Text = 'INPUT PARAMETERS';
            app.PLOTLabel_4 = uilabel(app.ValidationAgentbasedTab);
            app.PLOTLabel_4.Text = 'PLOT';
            app.UIAxes_5 = uiaxes(app.ValidationAgentbasedTab);
            title(app.UIAxes_5, 'Agent-based Model')
            xlabel(app.UIAxes_5, 'Width')
            ylabel(app.UIAxes_5, 'Height')
            app.UIAxes_5.XLim = [0 1];
            app.UIAxes_6 = uiaxes(app.ValidationAgentbasedTab);
            title(app.UIAxes_6, 'Compartmental Model')
            xlabel(app.UIAxes_6, 'Time (days)')
            ylabel(app.UIAxes_6, 'Number of people')
            app.CONTROLSLabel_4 = uilabel(app.ValidationAgentbasedTab);
            app.CONTROLSLabel_4.Text = 'CONTROLS';
            app.CLEARButton_4 = uibutton(app.ValidationAgentbasedTab, 'push');
            app.CLEARButton_4.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_4Pushed, true);
            app.CLEARButton_4.Text = 'CLEAR';
            app.STARTButton_4 = uibutton(app.ValidationAgentbasedTab, 'push');
            app.STARTButton_4.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_4Pushed, true);
            app.STARTButton_4.Text = 'START';
            app.Knob_2 = uiknob(app.ValidationAgentbasedTab, 'continuous');
            app.Knob_2.MajorTicks = [0 20 40 60 80 100];
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.ErrorLabel = uilabel(app.ValidationAgentbasedTab);
            app.ErrorLabel.Text = 'Error';
            app.TimedaysKnob_3Label = uilabel(app.ValidationAgentbasedTab);
            app.TimedaysKnob_3Label.Text = 'Time (days)';
            app.TimedaysKnob_3 = uiknob(app.ValidationAgentbasedTab, 'continuous');
            app.TimedaysKnob_3.Limits = [0 250];
            app.TimedaysKnob_3.ValueChangedFcn = createCallbackFcn(app, @TimedaysKnob_3ValueChanged, true);
            app.TimedaysKnob_3.Value = 100;
            app.IKnob_2Label = uilabel(app.ValidationAgentbasedTab);
            app.IKnob_2Label.Text = 'I';
            app.IKnob_2 = uiknob(app.ValidationAgentbasedTab, 'continuous');
            app.IKnob_2.Limits = [0 30];
            app.IKnob_2.MajorTicks = [0 5 10 15 20 25 30];
            app.IKnob_2.ValueChangedFcn = createCallbackFcn(app, @IKnob_2ValueChanged, true);
            app.IKnob_2.Value = 1;
            app.RKnob_2Label = uilabel(app.ValidationAgentbasedTab);
            app.RKnob_2Label.Text = 'R';
            app.RKnob_2 = uiknob(app.ValidationAgentbasedTab, 'continuous');
            app.RKnob_2.Limits = [0 10];
            app.RKnob_2.MajorTicks = [0 2 4 6 8 10];
            app.RKnob_2.ValueChangedFcn = createCallbackFcn(app, @RKnob_2ValueChanged, true);
            app.RKnob_2.Value = 1;
            app.PMovementSpinner_2Label = uilabel(app.ValidationAgentbasedTab);
            app.PMovementSpinner_2Label.Text = 'P - Movement';
            app.PMovementSpinner_2 = uispinner(app.ValidationAgentbasedTab);
            app.PMovementSpinner_2.Step = 0.05;
            app.PMovementSpinner_2.Limits = [0 1];
            app.PMovementSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PMovementSpinner_2ValueChanged, true);
            app.PMovementSpinner_2.Value = 1;
            app.PTransmitSpinner_2Label = uilabel(app.ValidationAgentbasedTab);
            app.PTransmitSpinner_2Label.Text = 'P - Transmit';
            app.PTransmitSpinner_2 = uispinner(app.ValidationAgentbasedTab);
            app.PTransmitSpinner_2.Step = 0.05;
            app.PTransmitSpinner_2.Limits = [0 1];
            app.PTransmitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PTransmitSpinner_2ValueChanged, true);
            app.PTransmitSpinner_2.Value = 0.7;
            app.PDeathUSpinner_2Label = uilabel(app.ValidationAgentbasedTab);
            app.PDeathUSpinner_2Label.Text = 'P - Death (U)';
            app.PDeathUSpinner_2 = uispinner(app.ValidationAgentbasedTab);
            app.PDeathUSpinner_2.Step = 0.05;
            app.PDeathUSpinner_2.Limits = [0 1];
            app.PDeathUSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PDeathUSpinner_2ValueChanged, true);
            app.PDeathUSpinner_2.Value = 0.7;
            app.PDeathHSpinner_2Label = uilabel(app.ValidationAgentbasedTab);
            app.PDeathHSpinner_2Label.Text = 'P - Death (H)';
            app.PDeathHSpinner_2 = uispinner(app.ValidationAgentbasedTab);
            app.PDeathHSpinner_2.Step = 0.05;
            app.PDeathHSpinner_2.Limits = [0 1];
            app.PDeathHSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PDeathHSpinner_2ValueChanged, true);
            app.PDeathHSpinner_2.Value = 0.5;
            app.PSeekingHSpinner_2Label = uilabel(app.ValidationAgentbasedTab);
            app.PSeekingHSpinner_2Label.Text = 'P - Seeking H';
            app.PSeekingHSpinner_2 = uispinner(app.ValidationAgentbasedTab);
            app.PSeekingHSpinner_2.Step = 0.05;
            app.PSeekingHSpinner_2.Limits = [0 1];
            app.PSeekingHSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PSeekingHSpinner_2ValueChanged, true);
            app.PSeekingHSpinner_2.Value = 0.2;
            app.SusceptibleTextArea_2Label = uilabel(app.ValidationAgentbasedTab);
            app.SusceptibleTextArea_2Label.Text = 'Susceptible';
            app.SusceptibleTextArea_2 = uitextarea(app.ValidationAgentbasedTab);
            app.SusceptibleTextArea_2.ValueChangedFcn = createCallbackFcn(app, @SusceptibleTextArea_2ValueChanged, true);
            app.InfectedTextArea_4Label = uilabel(app.ValidationAgentbasedTab);
            app.InfectedTextArea_4Label.Text = 'Infected';
            app.InfectedTextArea_4 = uitextarea(app.ValidationAgentbasedTab);
            app.InfectedTextArea_4.ValueChangedFcn = createCallbackFcn(app, @InfectedTextArea_4ValueChanged, true);
            app.RecoveredTextArea_4Label = uilabel(app.ValidationAgentbasedTab);
            app.RecoveredTextArea_4Label.Text = 'Recovered';
            app.RecoveredTextArea_4 = uitextarea(app.ValidationAgentbasedTab);
            app.RecoveredTextArea_4.ValueChangedFcn = createCallbackFcn(app, @RecoveredTextArea_4ValueChanged, true);
            app.RMSETextArea_2Label = uilabel(app.ValidationAgentbasedTab);
            app.RMSETextArea_2Label.Text = 'RMSE';
            app.RMSETextArea_2 = uitextarea(app.ValidationAgentbasedTab);
            app.RMSETextArea_2.ValueChangedFcn = createCallbackFcn(app, @RMSETextArea_2ValueChanged, true);
            app.CompartmentalModelTab = uitab(app.TabGroup);
            app.CompartmentalModelTab.Title = 'Compartmental Model';
            app.CompartmentsButtonGroup = uibuttongroup(app.CompartmentalModelTab);
            app.CompartmentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Compartments, true);
            app.CompartmentsButtonGroup.Title = 'Compartments';
            app.SEIRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRButton.Text = 'SEIR';
            app.SEIRButton.Value = true;
            app.SEIHRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRButton.Text = 'SEIHR';
            app.SEIRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRDButton.Text = 'SEIRD';
            app.SEIHRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRDButton.Text = 'SEIHRD';
            app.SEIHRFDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRFDButton.Text = 'SEIHRFD';
            app.FINISHButton = uibutton(app.CompartmentalModelTab, 'push');
            app.FINISHButton.ButtonPushedFcn = createCallbackFcn(app, @FINISHButtonPushed, true);
            app.FINISHButton.Text = 'FINISH';
            app.UIAxes = uiaxes(app.CompartmentalModelTab);
            title(app.UIAxes, 'Compartmental Model')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Number of people')
            app.INPUTPARAMETERSLabel = uilabel(app.CompartmentalModelTab);
            app.INPUTPARAMETERSLabel.Text = 'INPUT PARAMETERS';
            app.CONTROLSLabel = uilabel(app.CompartmentalModelTab);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.MODELSLabel = uilabel(app.CompartmentalModelTab);
            app.MODELSLabel.Text = 'MODELS';
            app.PLOTLabel = uilabel(app.CompartmentalModelTab);
            app.PLOTLabel.Text = 'PLOT';
            app.CLEARButton = uibutton(app.CompartmentalModelTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.STARTButton = uibutton(app.CompartmentalModelTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NTotalPopulationSlider = uislider(app.CompartmentalModelTab);
            app.NTotalPopulationSlider.Limits = [0 30000];
            app.NTotalPopulationSlider.MajorTicks = [0 10000 20000 30000];
            app.NTotalPopulationSlider.ValueChangedFcn = createCallbackFcn(app, @N, true);
            app.NTotalPopulationSlider.Value = 3900;
            app.BetaCommunityContactRateSlider = uislider(app.CompartmentalModelTab);
            app.BetaCommunityContactRateSlider.Limits = [0 1];
            app.BetaCommunityContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @Beta, true);
            app.BetaCommunityContactRateSlider.Value = 0.23;
            app.TimedaysKnobLabel = uilabel(app.CompartmentalModelTab);
            app.TimedaysKnobLabel.Text = 'Time (days)';
            app.TimedaysKnob = uiknob(app.CompartmentalModelTab, 'continuous');
            app.TimedaysKnob.Limits = [0 250];
            app.TimedaysKnob.ValueChangedFcn = createCallbackFcn(app, @TimedaysKnobValueChanged, true);
            app.SusceptiblesTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.SusceptiblesTextAreaLabel.Text = 'Susceptibles';
            app.SusceptiblesTextArea = uitextarea(app.CompartmentalModelTab);
            app.SusceptiblesTextArea.ValueChangedFcn = createCallbackFcn(app, @SusceptiblesTextAreaValueChanged, true);
            app.FuneralTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.FuneralTextAreaLabel.Text = 'Funeral';
            app.FuneralTextArea = uitextarea(app.CompartmentalModelTab);
            app.FuneralTextArea.ValueChangedFcn = createCallbackFcn(app, @FuneralTextAreaValueChanged, true);
            app.InfectedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.InfectedTextAreaLabel.Text = 'Infected';
            app.InfectedTextArea = uitextarea(app.CompartmentalModelTab);
            app.InfectedTextArea.ValueChangedFcn = createCallbackFcn(app, @InfectedTextAreaValueChanged, true);
            app.RecoveredTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.RecoveredTextAreaLabel.Text = 'Recovered';
            app.RecoveredTextArea = uitextarea(app.CompartmentalModelTab);
            app.RecoveredTextArea.ValueChangedFcn = createCallbackFcn(app, @RecoveredTextAreaValueChanged, true);
            app.ExposedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.ExposedTextAreaLabel.Text = 'Exposed';
            app.ExposedTextArea = uitextarea(app.CompartmentalModelTab);
            app.ExposedTextArea.ValueChangedFcn = createCallbackFcn(app, @ExposedTextAreaValueChanged, true);
            app.HospitalizedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.HospitalizedTextAreaLabel.Text = 'Hospitalized';
            app.HospitalizedTextArea = uitextarea(app.CompartmentalModelTab);
            app.HospitalizedTextArea.ValueChangedFcn = createCallbackFcn(app, @HospitalizedTextAreaValueChanged, true);
            app.DeadTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.DeadTextAreaLabel.Text = 'Dead';
            app.DeadTextArea = uitextarea(app.CompartmentalModelTab);
            app.DeadTextArea.ValueChangedFcn = createCallbackFcn(app, @DeadTextAreaValueChanged, true);
            app.MODELSPECIFICPARAMETERSLabel = uilabel(app.CompartmentalModelTab);
            app.MODELSPECIFICPARAMETERSLabel.Text = 'MODEL-SPECIFIC PARAMETERS';
            app.SEIRPanel = uipanel(app.CompartmentalModelTab);
            app.SEIRPanel.Title = 'SEIR';
            app.AlphaIncubationPeriodSliderLabel = uilabel(app.SEIRPanel);
            app.AlphaIncubationPeriodSliderLabel.Text = 'Alpha: Incubation Period ';
            app.AlphaIncubationPeriodSlider = uislider(app.SEIRPanel);
            app.AlphaIncubationPeriodSlider.Limits = [2 21];
            app.AlphaIncubationPeriodSlider.MajorTicks = [2 5 8 11 14 17 21];
            app.AlphaIncubationPeriodSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaIncubationPeriodSliderValueChanged, true);
            app.AlphaIncubationPeriodSlider.Value = 12;
            app.ThetaProbabilityofSurvivalSliderLabel = uilabel(app.SEIRPanel);
            app.ThetaProbabilityofSurvivalSliderLabel.Text = 'Theta: Probability of Survival';
            app.ThetaProbabilityofSurvivalSlider = uislider(app.SEIRPanel);
            app.ThetaProbabilityofSurvivalSlider.Limits = [0 1];
            app.ThetaProbabilityofSurvivalSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ThetaProbabilityofSurvivalSlider.ValueChangedFcn = createCallbackFcn(app, @ThetaProbabilityofSurvivalSliderValueChanged, true);
            app.ThetaProbabilityofSurvivalSlider.Value = 0.549;
            app.PiProbabilityofSeekingHospitalizationSliderLabel = uilabel(app.SEIRPanel);
            app.PiProbabilityofSeekingHospitalizationSliderLabel.Text = 'Pi: Probability of Seeking Hospitalization';
            app.PiProbabilityofSeekingHospitalizationSlider = uislider(app.SEIRPanel);
            app.PiProbabilityofSeekingHospitalizationSlider.Limits = [0 1];
            app.PiProbabilityofSeekingHospitalizationSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PiProbabilityofSeekingHospitalizationSlider.ValueChangedFcn = createCallbackFcn(app, @PiProbabilityofSeekingHospitalizationSliderValueChanged, true);
            app.PiProbabilityofSeekingHospitalizationSlider.Value = 0.197;
            app.e1TimefromInfectiontoRecoverySliderLabel = uilabel(app.SEIRPanel);
            app.e1TimefromInfectiontoRecoverySliderLabel.Text = 'e1: Time from Infection to Recovery';
            app.e1TimefromInfectiontoRecoverySlider = uislider(app.SEIRPanel);
            app.e1TimefromInfectiontoRecoverySlider.Limits = [0 20];
            app.e1TimefromInfectiontoRecoverySlider.MajorTicks = [0 5 10 15 20];
            app.e1TimefromInfectiontoRecoverySlider.ValueChangedFcn = createCallbackFcn(app, @e1TimefromInfectiontoRecoverySliderValueChanged, true);
            app.e1TimefromInfectiontoRecoverySlider.Value = 15;
            app.e2k2TimeuntilHospitalizationSliderLabel = uilabel(app.SEIRPanel);
            app.e2k2TimeuntilHospitalizationSliderLabel.Text = 'e2, k2: Time until Hospitalization';
            app.e2k2TimeuntilHospitalizationSlider = uislider(app.SEIRPanel);
            app.e2k2TimeuntilHospitalizationSlider.Limits = [0 5];
            app.e2k2TimeuntilHospitalizationSlider.MajorTicks = [0 1 2 3 4 5];
            app.e2k2TimeuntilHospitalizationSlider.ValueChangedFcn = createCallbackFcn(app, @e2k2TimeuntilHospitalizationSliderValueChanged, true);
            app.e2k2TimeuntilHospitalizationSlider.Value = 3.24;
            app.k1TimefromInfectiontoDeathSliderLabel = uilabel(app.SEIRPanel);
            app.k1TimefromInfectiontoDeathSliderLabel.Text = 'k1: Time from Infection to Death ';
            app.k1TimefromInfectiontoDeathSlider = uislider(app.SEIRPanel);
            app.k1TimefromInfectiontoDeathSlider.Limits = [0 30];
            app.k1TimefromInfectiontoDeathSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.k1TimefromInfectiontoDeathSlider.ValueChangedFcn = createCallbackFcn(app, @k1TimefromInfectiontoDeathSliderValueChanged, true);
            app.k1TimefromInfectiontoDeathSlider.Value = 13.31;
            app.NTotalPopulationSlider_2Label_2 = uilabel(app.CompartmentalModelTab);
            app.NTotalPopulationSlider_2Label_2.Text = 'N: Total Population';
            app.BetaCommunityContactRateSlider_2Label_2 = uilabel(app.CompartmentalModelTab);
            app.BetaCommunityContactRateSlider_2Label_2.Text = 'Beta(C): Community Contact Rate';
            app.HPanel = uipanel(app.CompartmentalModelTab);
            app.HPanel.Title = '+ H';
            app.BetaHHospitalContactRateSliderLabel = uilabel(app.HPanel);
            app.BetaHHospitalContactRateSliderLabel.Text = 'Beta(H): Hospital Contact Rate';
            app.BetaHHospitalContactRateSlider = uislider(app.HPanel);
            app.BetaHHospitalContactRateSlider.Limits = [0 0.1];
            app.BetaHHospitalContactRateSlider.MajorTicks = [0 0.025 0.05 0.075 0.1];
            app.BetaHHospitalContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @BetaHHospitalContactRateSliderValueChanged, true);
            app.BetaHHospitalContactRateSlider.Value = 0.062;
            app.DeltaTimefromHospitalizationtoDeathSliderLabel = uilabel(app.HPanel);
            app.DeltaTimefromHospitalizationtoDeathSliderLabel.Text = 'Delta: Time from Hospitalization to Death ';
            app.DeltaTimefromHospitalizationtoDeathSlider = uislider(app.HPanel);
            app.DeltaTimefromHospitalizationtoDeathSlider.Limits = [0 20];
            app.DeltaTimefromHospitalizationtoDeathSlider.MajorTicks = [0 4 8 12 16 20];
            app.DeltaTimefromHospitalizationtoDeathSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaTimefromHospitalizationtoDeathSliderValueChanged, true);
            app.DeltaTimefromHospitalizationtoDeathSlider.Value = 10.07;
            app.RoeTimefromHospitalizationtoRecoverySliderLabel = uilabel(app.HPanel);
            app.RoeTimefromHospitalizationtoRecoverySliderLabel.Text = 'Roe: Time from Hospitalization to Recovery ';
            app.RoeTimefromHospitalizationtoRecoverySlider = uislider(app.HPanel);
            app.RoeTimefromHospitalizationtoRecoverySlider.Limits = [0 20];
            app.RoeTimefromHospitalizationtoRecoverySlider.MajorTicks = [0 4 8 12 16 20];
            app.RoeTimefromHospitalizationtoRecoverySlider.ValueChangedFcn = createCallbackFcn(app, @RoeTimefromHospitalizationtoRecoverySliderValueChanged, true);
            app.RoeTimefromHospitalizationtoRecoverySlider.ValueChangingFcn = createCallbackFcn(app, @RoeTimefromHospitalizationtoRecoverySliderValueChanging, true);
            app.RoeTimefromHospitalizationtoRecoverySlider.Value = 15.88;
            app.DPanel = uipanel(app.CompartmentalModelTab);
            app.DPanel.Title = '+ D';
            app.DPanel.SizeChangedFcn = createCallbackFcn(app, @DPanelSizeChanged, true);
            app.GammaDurationofBurialTimeSliderLabel = uilabel(app.DPanel);
            app.GammaDurationofBurialTimeSliderLabel.Text = 'Gamma: Duration of Burial Time';
            app.GammaDurationofBurialTimeSlider = uislider(app.DPanel);
            app.GammaDurationofBurialTimeSlider.Limits = [0 5];
            app.GammaDurationofBurialTimeSlider.MajorTicks = [0 1 2 3 4 5];
            app.GammaDurationofBurialTimeSlider.ValueChangedFcn = createCallbackFcn(app, @GammaDurationofBurialTimeSliderValueChanged, true);
            app.GammaDurationofBurialTimeSlider.Value = 2.01;
            app.FPanel = uipanel(app.CompartmentalModelTab);
            app.FPanel.Title = '+ F';
            app.BetaFFuneralContactRateSliderLabel = uilabel(app.FPanel);
            app.BetaFFuneralContactRateSliderLabel.Text = 'Beta(F): Funeral Contact Rate ';
            app.BetaFFuneralContactRateSlider = uislider(app.FPanel);
            app.BetaFFuneralContactRateSlider.Limits = [0 1];
            app.BetaFFuneralContactRateSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.BetaFFuneralContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @BetaFFuneralContactRateSliderValueChanged, true);
            app.BetaFFuneralContactRateSlider.Value = 0.489;
            app.INITIALCONDITIONSLabel = uilabel(app.CompartmentalModelTab);
            app.INITIALCONDITIONSLabel.Text = 'INITIAL CONDITIONS';
            app.EKnobLabel = uilabel(app.CompartmentalModelTab);
            app.EKnobLabel.Text = 'E';
            app.EKnob = uiknob(app.CompartmentalModelTab, 'continuous');
            app.EKnob.ValueChangedFcn = createCallbackFcn(app, @EKnobValueChanged, true);
            app.EKnob.Value = 32;
            app.InfectedSpinnerLabel = uilabel(app.CompartmentalModelTab);
            app.InfectedSpinnerLabel.Text = 'Infected';
            app.InfectedSpinner = uispinner(app.CompartmentalModelTab);
            app.InfectedSpinner.Step = 5;
            app.InfectedSpinner.Limits = [0 Inf];
            app.InfectedSpinner.ValueChangedFcn = createCallbackFcn(app, @InfectedSpinnerValueChanged, true);
            app.InfectedSpinner.Value = 100;
            app.RecoveredSpinnerLabel = uilabel(app.CompartmentalModelTab);
            app.RecoveredSpinnerLabel.Text = 'Recovered';
            app.RecoveredSpinner = uispinner(app.CompartmentalModelTab);
            app.RecoveredSpinner.Step = 5;
            app.RecoveredSpinner.Limits = [0 Inf];
            app.RecoveredSpinner.ValueChangedFcn = createCallbackFcn(app, @RecoveredSpinnerValueChanged, true);
            app.RecoveredSpinner.Value = 10;
            app.DeadSpinnerLabel = uilabel(app.CompartmentalModelTab);
            app.DeadSpinnerLabel.Text = 'Dead';
            app.DeadSpinner = uispinner(app.CompartmentalModelTab);
            app.DeadSpinner.Step = 5;
            app.DeadSpinner.Limits = [0 Inf];
            app.DeadSpinner.ValueChangedFcn = createCallbackFcn(app, @DeadSpinnerValueChanged, true);
            app.DeadSpinner.Value = 50;
            app.HospitalizationSpinnerLabel = uilabel(app.CompartmentalModelTab);
            app.HospitalizationSpinnerLabel.Text = 'Hospitalization';
            app.HospitalizationSpinner = uispinner(app.CompartmentalModelTab);
            app.HospitalizationSpinner.Step = 5;
            app.HospitalizationSpinner.Limits = [0 Inf];
            app.HospitalizationSpinner.ValueChangedFcn = createCallbackFcn(app, @HospitalizationSpinnerValueChanged, true);
            app.HospitalizationSpinner.Value = 10;
            app.FuneralSpinnerLabel = uilabel(app.CompartmentalModelTab);
            app.FuneralSpinnerLabel.Text = 'Funeral';
            app.FuneralSpinner = uispinner(app.CompartmentalModelTab);
            app.FuneralSpinner.Step = 5;
            app.FuneralSpinner.Limits = [0 Inf];
            app.FuneralSpinner.ValueChangedFcn = createCallbackFcn(app, @FuneralSpinnerValueChanged, true);
            app.FuneralSpinner.Value = 30;
            app.ValidationCompartmentalTab = uitab(app.TabGroup);
            app.ValidationCompartmentalTab.Title = 'Validation -- Compartmental';
            app.CompartmentsButtonGroup_2 = uibuttongroup(app.ValidationCompartmentalTab);
            app.CompartmentsButtonGroup_2.Title = 'Compartments';
            app.SEIRButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIRButton_2.Text = 'SEIR';
            app.SEIRButton_2.Value = true;
            app.SEIHRButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRButton_2.Text = 'SEIHR';
            app.SEIRDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIRDButton_2.Text = 'SEIRD';
            app.SEIHRDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRDButton_2.Text = 'SEIHRD';
            app.SEIHRFDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRFDButton_2.Text = 'SEIHRFD';
            app.UIAxes_2 = uiaxes(app.ValidationCompartmentalTab);
            title(app.UIAxes_2, 'Model vs. Reality')
            xlabel(app.UIAxes_2, 'Time (days)')
            ylabel(app.UIAxes_2, 'Number of people')
            app.INPUTPARAMETERSLabel_2 = uilabel(app.ValidationCompartmentalTab);
            app.INPUTPARAMETERSLabel_2.Text = 'INPUT PARAMETERS';
            app.CONTROLSLabel_2 = uilabel(app.ValidationCompartmentalTab);
            app.CONTROLSLabel_2.Text = 'CONTROLS';
            app.MODELSLabel_2 = uilabel(app.ValidationCompartmentalTab);
            app.MODELSLabel_2.Text = 'MODELS';
            app.PLOTLabel_2 = uilabel(app.ValidationCompartmentalTab);
            app.PLOTLabel_2.Text = 'PLOT';
            app.CLEARButton_2 = uibutton(app.ValidationCompartmentalTab, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.NTotalPopulationSlider_2Label = uilabel(app.ValidationCompartmentalTab);
            app.NTotalPopulationSlider_2Label.Text = 'N: Total Population';
            app.NTotalPopulationSlider_2 = uislider(app.ValidationCompartmentalTab);
            app.NTotalPopulationSlider_2.Limits = [0 10000];
            app.NTotalPopulationSlider_2.ValueChangedFcn = createCallbackFcn(app, @NTotalPopulationSlider_2ValueChanged, true);
            app.NTotalPopulationSlider_2.Value = 3900;
            app.BetaCommunityContactRateSlider_2 = uislider(app.ValidationCompartmentalTab);
            app.BetaCommunityContactRateSlider_2.Limits = [0 1];
            app.BetaCommunityContactRateSlider_2.ValueChangedFcn = createCallbackFcn(app, @BetaCommunityContactRateSlider_2ValueChanged, true);
            app.BetaCommunityContactRateSlider_2.Value = 0.23;
            app.STARTButton_2 = uibutton(app.ValidationCompartmentalTab, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.RMSETextAreaLabel = uilabel(app.ValidationCompartmentalTab);
            app.RMSETextAreaLabel.Text = 'RMSE';
            app.RMSETextArea = uitextarea(app.ValidationCompartmentalTab);
            app.RMSETextArea.ValueChangedFcn = createCallbackFcn(app, @RMSETextAreaValueChanged, true);
            app.ERRORLabel = uilabel(app.ValidationCompartmentalTab);
            app.ERRORLabel.Text = 'ERROR';
            app.BetaCommunityContactRateSlider_2Label_3 = uilabel(app.ValidationCompartmentalTab);
            app.BetaCommunityContactRateSlider_2Label_3.Text = 'Beta(C): Community Contact Rate';
            app.MODELSPECIFICPARAMETERSLabel_2 = uilabel(app.ValidationCompartmentalTab);
            app.MODELSPECIFICPARAMETERSLabel_2.Text = 'MODEL-SPECIFIC PARAMETERS';
            app.SEIRPanel_2 = uipanel(app.ValidationCompartmentalTab);
            app.SEIRPanel_2.Title = 'SEIR';
            app.AlphaIncubationPeriodSlider_2Label = uilabel(app.SEIRPanel_2);
            app.AlphaIncubationPeriodSlider_2Label.Text = 'Alpha: Incubation Period ';
            app.AlphaIncubationPeriodSlider_2 = uislider(app.SEIRPanel_2);
            app.AlphaIncubationPeriodSlider_2.Limits = [2 21];
            app.AlphaIncubationPeriodSlider_2.MajorTicks = [2 5 8 11 14 17 21];
            app.AlphaIncubationPeriodSlider_2.ValueChangedFcn = createCallbackFcn(app, @AlphaIncubationPeriodSlider_2ValueChanged, true);
            app.AlphaIncubationPeriodSlider_2.Value = 12;
            app.ThetaProbabilityofSurvivalSlider_2Label = uilabel(app.SEIRPanel_2);
            app.ThetaProbabilityofSurvivalSlider_2Label.Text = 'Theta: Probability of Survival';
            app.ThetaProbabilityofSurvivalSlider_2 = uislider(app.SEIRPanel_2);
            app.ThetaProbabilityofSurvivalSlider_2.Limits = [0 1];
            app.ThetaProbabilityofSurvivalSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ThetaProbabilityofSurvivalSlider_2.ValueChangedFcn = createCallbackFcn(app, @ThetaProbabilityofSurvivalSlider_2ValueChanged, true);
            app.ThetaProbabilityofSurvivalSlider_2.Value = 0.549;
            app.PiProbabilityofSeekingHospitalizationSlider_2Label = uilabel(app.SEIRPanel_2);
            app.PiProbabilityofSeekingHospitalizationSlider_2Label.Text = 'Pi: Probability of Seeking Hospitalization';
            app.PiProbabilityofSeekingHospitalizationSlider_2 = uislider(app.SEIRPanel_2);
            app.PiProbabilityofSeekingHospitalizationSlider_2.Limits = [0 1];
            app.PiProbabilityofSeekingHospitalizationSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PiProbabilityofSeekingHospitalizationSlider_2.ValueChangedFcn = createCallbackFcn(app, @PiProbabilityofSeekingHospitalizationSlider_2ValueChanged, true);
            app.PiProbabilityofSeekingHospitalizationSlider_2.Value = 0.197;
            app.e1TimefromInfectiontoRecoverySlider_2Label = uilabel(app.SEIRPanel_2);
            app.e1TimefromInfectiontoRecoverySlider_2Label.Text = 'e1: Time from Infection to Recovery';
            app.e1TimefromInfectiontoRecoverySlider_2 = uislider(app.SEIRPanel_2);
            app.e1TimefromInfectiontoRecoverySlider_2.Limits = [0 20];
            app.e1TimefromInfectiontoRecoverySlider_2.MajorTicks = [0 5 10 15 20];
            app.e1TimefromInfectiontoRecoverySlider_2.ValueChangedFcn = createCallbackFcn(app, @e1TimefromInfectiontoRecoverySlider_2ValueChanged, true);
            app.e1TimefromInfectiontoRecoverySlider_2.Value = 15;
            app.e2k2TimeuntilHospitalizationSlider_2Label = uilabel(app.SEIRPanel_2);
            app.e2k2TimeuntilHospitalizationSlider_2Label.Text = 'e2, k2: Time until Hospitalization';
            app.e2k2TimeuntilHospitalizationSlider_2 = uislider(app.SEIRPanel_2);
            app.e2k2TimeuntilHospitalizationSlider_2.Limits = [0 5];
            app.e2k2TimeuntilHospitalizationSlider_2.MajorTicks = [0 1 2 3 4 5];
            app.e2k2TimeuntilHospitalizationSlider_2.ValueChangedFcn = createCallbackFcn(app, @e2k2TimeuntilHospitalizationSlider_2ValueChanged, true);
            app.e2k2TimeuntilHospitalizationSlider_2.Value = 3.24;
            app.k1TimefromInfectiontoDeathSlider_2Label = uilabel(app.SEIRPanel_2);
            app.k1TimefromInfectiontoDeathSlider_2Label.Text = 'k1: Time from Infection to Death ';
            app.k1TimefromInfectiontoDeathSlider_2 = uislider(app.SEIRPanel_2);
            app.k1TimefromInfectiontoDeathSlider_2.Limits = [0 30];
            app.k1TimefromInfectiontoDeathSlider_2.MajorTicks = [0 5 10 15 20 25 30];
            app.k1TimefromInfectiontoDeathSlider_2.ValueChangedFcn = createCallbackFcn(app, @k1TimefromInfectiontoDeathSlider_2ValueChanged, true);
            app.k1TimefromInfectiontoDeathSlider_2.Value = 13.31;
            app.HPanel_2 = uipanel(app.ValidationCompartmentalTab);
            app.HPanel_2.Title = '+ H';
            app.BetaHHospitalContactRateSlider_2Label = uilabel(app.HPanel_2);
            app.BetaHHospitalContactRateSlider_2Label.Text = 'Beta(H): Hospital Contact Rate';
            app.BetaHHospitalContactRateSlider_2 = uislider(app.HPanel_2);
            app.BetaHHospitalContactRateSlider_2.Limits = [0 0.1];
            app.BetaHHospitalContactRateSlider_2.MajorTicks = [0 0.025 0.05 0.075 0.1];
            app.BetaHHospitalContactRateSlider_2.ValueChangedFcn = createCallbackFcn(app, @BetaHHospitalContactRateSlider_2ValueChanged, true);
            app.BetaHHospitalContactRateSlider_2.Value = 0.062;
            app.DeltaTimefromHospitalizationtoDeathSlider_2Label = uilabel(app.HPanel_2);
            app.DeltaTimefromHospitalizationtoDeathSlider_2Label.Text = 'Delta: Time from Hospitalization to Death ';
            app.DeltaTimefromHospitalizationtoDeathSlider_2 = uislider(app.HPanel_2);
            app.DeltaTimefromHospitalizationtoDeathSlider_2.Limits = [0 20];
            app.DeltaTimefromHospitalizationtoDeathSlider_2.MajorTicks = [0 4 8 12 16 20];
            app.DeltaTimefromHospitalizationtoDeathSlider_2.ValueChangedFcn = createCallbackFcn(app, @DeltaTimefromHospitalizationtoDeathSlider_2ValueChanged, true);
            app.DeltaTimefromHospitalizationtoDeathSlider_2.Value = 10.07;
            app.RoeTimefromHospitalizationtoRecoverySlider_2Label = uilabel(app.HPanel_2);
            app.RoeTimefromHospitalizationtoRecoverySlider_2Label.Text = 'Roe: Time from Hospitalization to Recovery ';
            app.RoeTimefromHospitalizationtoRecoverySlider_2 = uislider(app.HPanel_2);
            app.RoeTimefromHospitalizationtoRecoverySlider_2.Limits = [0 20];
            app.RoeTimefromHospitalizationtoRecoverySlider_2.MajorTicks = [0 4 8 12 16 20];
            app.RoeTimefromHospitalizationtoRecoverySlider_2.ValueChangedFcn = createCallbackFcn(app, @RoeTimefromHospitalizationtoRecoverySlider_2ValueChanged, true);
            app.RoeTimefromHospitalizationtoRecoverySlider_2.Value = 15.88;
            app.DPanel_2 = uipanel(app.ValidationCompartmentalTab);
            app.DPanel_2.Title = '+ D';
            app.GammaDurationofBurialTimeSlider_2Label = uilabel(app.DPanel_2);
            app.GammaDurationofBurialTimeSlider_2Label.Text = 'Gamma: Duration of Burial Time';
            app.GammaDurationofBurialTimeSlider_2 = uislider(app.DPanel_2);
            app.GammaDurationofBurialTimeSlider_2.Limits = [0 5];
            app.GammaDurationofBurialTimeSlider_2.MajorTicks = [0 1 2 3 4 5];
            app.GammaDurationofBurialTimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @GammaDurationofBurialTimeSlider_2ValueChanged, true);
            app.GammaDurationofBurialTimeSlider_2.Value = 2.01;
            app.FPanel_2 = uipanel(app.ValidationCompartmentalTab);
            app.FPanel_2.Title = '+ F';
            app.BetaFFuneralContactRateSlider_2Label = uilabel(app.FPanel_2);
            app.BetaFFuneralContactRateSlider_2Label.Text = 'Beta(F): Funeral Contact Rate ';
            app.BetaFFuneralContactRateSlider_2 = uislider(app.FPanel_2);
            app.BetaFFuneralContactRateSlider_2.Limits = [0 1];
            app.BetaFFuneralContactRateSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.BetaFFuneralContactRateSlider_2.ValueChangedFcn = createCallbackFcn(app, @BetaFFuneralContactRateSlider_2ValueChanged, true);
            app.BetaFFuneralContactRateSlider_2.Value = 0.489;
            app.INITIALCONDITIONSLabel_2 = uilabel(app.ValidationCompartmentalTab);
            app.INITIALCONDITIONSLabel_2.Text = 'INITIAL CONDITIONS';
            app.EKnob_2Label = uilabel(app.ValidationCompartmentalTab);
            app.EKnob_2Label.Text = 'E';
            app.EKnob_2 = uiknob(app.ValidationCompartmentalTab, 'continuous');
            app.EKnob_2.ValueChangedFcn = createCallbackFcn(app, @EKnob_2ValueChanged, true);
            app.EKnob_2.Value = 32;
            app.InfectedSpinner_2Label = uilabel(app.ValidationCompartmentalTab);
            app.InfectedSpinner_2Label.Text = 'Infected';
            app.InfectedSpinner_2 = uispinner(app.ValidationCompartmentalTab);
            app.InfectedSpinner_2.Step = 5;
            app.InfectedSpinner_2.Limits = [0 Inf];
            app.InfectedSpinner_2.ValueChangedFcn = createCallbackFcn(app, @InfectedSpinner_2ValueChanged, true);
            app.InfectedSpinner_2.Value = 100;
            app.RecoveredSpinner_2Label = uilabel(app.ValidationCompartmentalTab);
            app.RecoveredSpinner_2Label.Text = 'Recovered';
            app.RecoveredSpinner_2 = uispinner(app.ValidationCompartmentalTab);
            app.RecoveredSpinner_2.Step = 5;
            app.RecoveredSpinner_2.Limits = [0 Inf];
            app.RecoveredSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RecoveredSpinner_2ValueChanged, true);
            app.RecoveredSpinner_2.Value = 10;
            app.DeadSpinner_2Label = uilabel(app.ValidationCompartmentalTab);
            app.DeadSpinner_2Label.Text = 'Dead';
            app.DeadSpinner_2 = uispinner(app.ValidationCompartmentalTab);
            app.DeadSpinner_2.Step = 5;
            app.DeadSpinner_2.Limits = [0 Inf];
            app.DeadSpinner_2.ValueChangedFcn = createCallbackFcn(app, @DeadSpinner_2ValueChanged, true);
            app.DeadSpinner_2.Value = 50;
            app.HospitalizationSpinner_2Label = uilabel(app.ValidationCompartmentalTab);
            app.HospitalizationSpinner_2Label.Text = 'Hospitalization';
            app.HospitalizationSpinner_2 = uispinner(app.ValidationCompartmentalTab);
            app.HospitalizationSpinner_2.Step = 5;
            app.HospitalizationSpinner_2.Limits = [0 Inf];
            app.HospitalizationSpinner_2.ValueChangedFcn = createCallbackFcn(app, @HospitalizationSpinner_2ValueChanged, true);
            app.HospitalizationSpinner_2.Value = 10;
            app.FuneralSpinner_2Label = uilabel(app.ValidationCompartmentalTab);
            app.FuneralSpinner_2Label.Text = 'Funeral';
            app.FuneralSpinner_2 = uispinner(app.ValidationCompartmentalTab);
            app.FuneralSpinner_2.Step = 5;
            app.FuneralSpinner_2.Limits = [0 Inf];
            app.FuneralSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FuneralSpinner_2ValueChanged, true);
            app.FuneralSpinner_2.Value = 30;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_11

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end