classdef GUI_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CompartmentsButtonGroup    matlab.ui.container.ButtonGroup
        SEIRButton                 matlab.ui.control.RadioButton
        SEIHRButton                matlab.ui.control.RadioButton
        SEIRDButton                matlab.ui.control.RadioButton
        SEIHRDButton               matlab.ui.control.RadioButton
        SEIHRFDButton              matlab.ui.control.RadioButton
        NSliderLabel               matlab.ui.control.Label
        NSlider                    matlab.ui.control.Slider
        BetaSliderLabel            matlab.ui.control.Label
        BetaSlider                 matlab.ui.control.Slider
        FINISHButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        INPUTPARAMETERSLabel       matlab.ui.control.Label
        CONTROLSLabel              matlab.ui.control.Label
        MODELSLabel                matlab.ui.control.Label
        PLOTLabel                  matlab.ui.control.Label
        CLEARButton                matlab.ui.control.Button
        STARTButton                matlab.ui.control.Button
        TimedaysKnobLabel          matlab.ui.control.Label
        TimedaysKnob               matlab.ui.control.Knob
        SusceptiblesTextAreaLabel  matlab.ui.control.Label
        SusceptiblesTextArea       matlab.ui.control.TextArea
        FuneralTextAreaLabel       matlab.ui.control.Label
        FuneralTextArea            matlab.ui.control.TextArea
        InfectedTextAreaLabel      matlab.ui.control.Label
        InfectedTextArea           matlab.ui.control.TextArea
        RecoveredTextAreaLabel     matlab.ui.control.Label
        RecoveredTextArea          matlab.ui.control.TextArea
        ExposedTextAreaLabel       matlab.ui.control.Label
        ExposedTextArea            matlab.ui.control.TextArea
        HospitalizedTextAreaLabel  matlab.ui.control.Label
        HospitalizedTextArea       matlab.ui.control.TextArea
        DeadTextAreaLabel          matlab.ui.control.Label
        DeadTextArea               matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Value changed function: NSlider
        function N(app, event)
            value = app.NSlider.Value;
            
        end

        % Value changed function: BetaSlider
        function Beta(app, event)
            value = app.BetaSlider.Value;
            
        end

        % Selection changed function: CompartmentsButtonGroup
        function Compartments(app, event)
            selectedButton = app.CompartmentsButtonGroup.SelectedObject;
            
        end

        % Button pushed function: FINISHButton
        function FINISHButtonPushed(app, event)
            if(app.SEIRButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                
            end 
            
            if(app.SEIHRButton.Value)
                plot(app.UIAxes, 0, 0);        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];

            end 
            
            if(app.SEIRDButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIHRDButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');    
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),7)];
            end 
            
            if(app.SEIHRFDButton.Value)
                plot(app.UIAxes, 0, 0);   
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;
                
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.FuneralTextArea.Value = [mat2str(xa(length(tspan)),7)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),8)];                
            end 
                
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            plot(app.UIAxes, 0, 0);
            legend(app.UIAxes, 'No Data');
            
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);  
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.SEIRButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
            end 
            
            if(app.SEIHRButton.Value)
                plot(app.UIAxes, 0, 0);       
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');

                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIRDButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIHRDButton.Value)
                plot(app.UIAxes, 0, 0);        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');      
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),7)];

            end 
            
            if(app.SEIHRFDButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;
                
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.FuneralTextArea.Value = [mat2str(xa(length(tspan)),7)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),8)];
            end 
        end

        % Value changed function: TimedaysKnob
        function TimedaysKnobValueChanged(app, event)
            value = app.TimedaysKnob.Value;
        end

        % Value changed function: SusceptiblesTextArea
        function SusceptiblesTextAreaValueChanged(app, event)
            value = app.SusceptiblesTextArea.Value;
        end

        % Value changed function: FuneralTextArea
        function FuneralTextAreaValueChanged(app, event)
            value = app.FuneralTextArea.Value;
            
        end

        % Value changed function: InfectedTextArea
        function InfectedTextAreaValueChanged(app, event)
            value = app.InfectedTextArea.Value;
            
        end

        % Value changed function: RecoveredTextArea
        function RecoveredTextAreaValueChanged(app, event)
            value = app.RecoveredTextArea.Value;
            
        end

        % Value changed function: ExposedTextArea
        function ExposedTextAreaValueChanged(app, event)
            value = app.ExposedTextArea.Value;
            
        end

        % Value changed function: HospitalizedTextArea
        function HospitalizedTextAreaValueChanged(app, event)
            value = app.HospitalizedTextArea.Value;
            
        end

        % Value changed function: DeadTextArea
        function DeadTextAreaValueChanged(app, event)
            value = app.DeadTextArea.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CompartmentsButtonGroup = uibuttongroup(app.UIFigure);
            app.CompartmentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Compartments, true);
            app.CompartmentsButtonGroup.Title = 'Compartments';
            app.SEIRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRButton.Text = 'SEIR';
            app.SEIRButton.Value = true;
            app.SEIHRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRButton.Text = 'SEIHR';
            app.SEIRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRDButton.Text = 'SEIRD';
            app.SEIHRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRDButton.Text = 'SEIHRD';
            app.SEIHRFDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRFDButton.Text = 'SEIHRFD';
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [0 30000];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @N, true);
            app.BetaSliderLabel = uilabel(app.UIFigure);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.UIFigure);
            app.BetaSlider.Limits = [0 1];
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @Beta, true);
            app.FINISHButton = uibutton(app.UIFigure, 'push');
            app.FINISHButton.ButtonPushedFcn = createCallbackFcn(app, @FINISHButtonPushed, true);
            app.FINISHButton.Text = 'FINISH';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Compartmental Model')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Number of people')
            app.INPUTPARAMETERSLabel = uilabel(app.UIFigure);
            app.INPUTPARAMETERSLabel.Text = 'INPUT PARAMETERS';
            app.CONTROLSLabel = uilabel(app.UIFigure);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.MODELSLabel = uilabel(app.UIFigure);
            app.MODELSLabel.Text = 'MODELS';
            app.PLOTLabel = uilabel(app.UIFigure);
            app.PLOTLabel.Text = 'PLOT';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.TimedaysKnobLabel = uilabel(app.UIFigure);
            app.TimedaysKnobLabel.Text = 'Time (days)';
            app.TimedaysKnob = uiknob(app.UIFigure, 'continuous');
            app.TimedaysKnob.Limits = [0 250];
            app.TimedaysKnob.ValueChangedFcn = createCallbackFcn(app, @TimedaysKnobValueChanged, true);
            app.SusceptiblesTextAreaLabel = uilabel(app.UIFigure);
            app.SusceptiblesTextAreaLabel.Text = 'Susceptibles';
            app.SusceptiblesTextArea = uitextarea(app.UIFigure);
            app.SusceptiblesTextArea.ValueChangedFcn = createCallbackFcn(app, @SusceptiblesTextAreaValueChanged, true);
            app.FuneralTextAreaLabel = uilabel(app.UIFigure);
            app.FuneralTextAreaLabel.Text = 'Funeral';
            app.FuneralTextArea = uitextarea(app.UIFigure);
            app.FuneralTextArea.ValueChangedFcn = createCallbackFcn(app, @FuneralTextAreaValueChanged, true);
            app.InfectedTextAreaLabel = uilabel(app.UIFigure);
            app.InfectedTextAreaLabel.Text = 'Infected';
            app.InfectedTextArea = uitextarea(app.UIFigure);
            app.InfectedTextArea.ValueChangedFcn = createCallbackFcn(app, @InfectedTextAreaValueChanged, true);
            app.RecoveredTextAreaLabel = uilabel(app.UIFigure);
            app.RecoveredTextAreaLabel.Text = 'Recovered';
            app.RecoveredTextArea = uitextarea(app.UIFigure);
            app.RecoveredTextArea.ValueChangedFcn = createCallbackFcn(app, @RecoveredTextAreaValueChanged, true);
            app.ExposedTextAreaLabel = uilabel(app.UIFigure);
            app.ExposedTextAreaLabel.Text = 'Exposed';
            app.ExposedTextArea = uitextarea(app.UIFigure);
            app.ExposedTextArea.ValueChangedFcn = createCallbackFcn(app, @ExposedTextAreaValueChanged, true);
            app.HospitalizedTextAreaLabel = uilabel(app.UIFigure);
            app.HospitalizedTextAreaLabel.Text = 'Hospitalized';
            app.HospitalizedTextArea = uitextarea(app.UIFigure);
            app.HospitalizedTextArea.ValueChangedFcn = createCallbackFcn(app, @HospitalizedTextAreaValueChanged, true);
            app.DeadTextAreaLabel = uilabel(app.UIFigure);
            app.DeadTextAreaLabel.Text = 'Dead';
            app.DeadTextArea = uitextarea(app.UIFigure);
            app.DeadTextArea.ValueChangedFcn = createCallbackFcn(app, @DeadTextAreaValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end