classdef GUI_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CompartmentalModelTab         matlab.ui.container.Tab
        CompartmentsButtonGroup       matlab.ui.container.ButtonGroup
        SEIRButton                    matlab.ui.control.RadioButton
        SEIHRButton                   matlab.ui.control.RadioButton
        SEIRDButton                   matlab.ui.control.RadioButton
        SEIHRDButton                  matlab.ui.control.RadioButton
        SEIHRFDButton                 matlab.ui.control.RadioButton
        FINISHButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        INPUTPARAMETERSLabel          matlab.ui.control.Label
        CONTROLSLabel                 matlab.ui.control.Label
        MODELSLabel                   matlab.ui.control.Label
        PLOTLabel                     matlab.ui.control.Label
        CLEARButton                   matlab.ui.control.Button
        STARTButton                   matlab.ui.control.Button
        NSliderLabel                  matlab.ui.control.Label
        NSlider                       matlab.ui.control.Slider
        BetaSliderLabel               matlab.ui.control.Label
        BetaSlider                    matlab.ui.control.Slider
        TimedaysKnobLabel             matlab.ui.control.Label
        TimedaysKnob                  matlab.ui.control.Knob
        SusceptiblesTextAreaLabel     matlab.ui.control.Label
        SusceptiblesTextArea          matlab.ui.control.TextArea
        FuneralTextAreaLabel          matlab.ui.control.Label
        FuneralTextArea               matlab.ui.control.TextArea
        InfectedTextAreaLabel         matlab.ui.control.Label
        InfectedTextArea              matlab.ui.control.TextArea
        RecoveredTextAreaLabel        matlab.ui.control.Label
        RecoveredTextArea             matlab.ui.control.TextArea
        ExposedTextAreaLabel          matlab.ui.control.Label
        ExposedTextArea               matlab.ui.control.TextArea
        HospitalizedTextAreaLabel     matlab.ui.control.Label
        HospitalizedTextArea          matlab.ui.control.TextArea
        DeadTextAreaLabel             matlab.ui.control.Label
        DeadTextArea                  matlab.ui.control.TextArea
        AgentbasedModelTab            matlab.ui.container.Tab
        HybridModelTab                matlab.ui.container.Tab
        ValidationTab                 matlab.ui.container.Tab
        CompartmentsButtonGroup_2     matlab.ui.container.ButtonGroup
        SEIRButton_2                  matlab.ui.control.RadioButton
        SEIHRButton_2                 matlab.ui.control.RadioButton
        SEIRDButton_2                 matlab.ui.control.RadioButton
        SEIHRDButton_2                matlab.ui.control.RadioButton
        SEIHRFDButton_2               matlab.ui.control.RadioButton
        UIAxes_2                      matlab.ui.control.UIAxes
        INPUTPARAMETERSLabel_2        matlab.ui.control.Label
        CONTROLSLabel_2               matlab.ui.control.Label
        MODELSLabel_2                 matlab.ui.control.Label
        PLOTLabel_2                   matlab.ui.control.Label
        CLEARButton_2                 matlab.ui.control.Button
        NSlider_2Label                matlab.ui.control.Label
        NSlider_2                     matlab.ui.control.Slider
        BetaSlider_2Label             matlab.ui.control.Label
        BetaSlider_2                  matlab.ui.control.Slider
        STARTButton_2                 matlab.ui.control.Button
        LOADDATALabel                 matlab.ui.control.Label
        CDCCumulativeDataButtonGroup  matlab.ui.container.ButtonGroup
        Liberia2014Button             matlab.ui.control.RadioButton
        Liberia2015Button             matlab.ui.control.RadioButton
        Liberia2016Button             matlab.ui.control.RadioButton
        Guinea2014Button              matlab.ui.control.RadioButton
        Guinea2015Button              matlab.ui.control.RadioButton
        Guinea2016Button              matlab.ui.control.RadioButton
        SierraLeone2014Button         matlab.ui.control.RadioButton
        SierraLeone2015Button         matlab.ui.control.RadioButton
        SierraLeone2016Button         matlab.ui.control.RadioButton
        RMSETextAreaLabel             matlab.ui.control.Label
        RMSETextArea                  matlab.ui.control.TextArea
        ERRORLabel                    matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: FINISHButton
        function FINISHButtonPushed(app, event)
            if(app.SEIRButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                
            end 
            
            if(app.SEIHRButton.Value)
                plot(app.UIAxes, 0, 0);        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];

            end 
            
            if(app.SEIRDButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIHRDButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');    
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),7)];
            end 
            
            if(app.SEIHRFDButton.Value)
                plot(app.UIAxes, 0, 0);   
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;
                
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.FuneralTextArea.Value = [mat2str(xa(length(tspan)),7)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),8)];                
            end 
                
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            plot(app.UIAxes, 0, 0);
            legend(app.UIAxes, 'No Data');
            
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);  
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.SEIRButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
            end 
            
            if(app.SEIHRButton.Value)
                plot(app.UIAxes, 0, 0);       
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');

                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIRDButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIHRDButton.Value)
                plot(app.UIAxes, 0, 0);        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');      
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),7)];

            end 
            
            if(app.SEIHRFDButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NSlider.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;
                
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.FuneralTextArea.Value = [mat2str(xa(length(tspan)),7)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),8)];
            end 
        end

        % Value changed function: TimedaysKnob
        function TimedaysKnobValueChanged(app, event)
            value = app.TimedaysKnob.Value;
        end

        % Value changed function: SusceptiblesTextArea
        function SusceptiblesTextAreaValueChanged(app, event)
            value = app.SusceptiblesTextArea.Value;
        end

        % Selection changed function: CompartmentsButtonGroup
        function Compartments(app, event)
            selectedButton = app.CompartmentsButtonGroup.SelectedObject;
            
        end

        % Value changed function: NSlider
        function N(app, event)
            value = app.NSlider.Value;
            
        end

        % Value changed function: BetaSlider
        function Beta(app, event)
            value = app.BetaSlider.Value;
            
        end

        % Value changed function: FuneralTextArea
        function FuneralTextAreaValueChanged(app, event)
            value = app.FuneralTextArea.Value;
            
        end

        % Value changed function: InfectedTextArea
        function InfectedTextAreaValueChanged(app, event)
            value = app.InfectedTextArea.Value;
            
        end

        % Value changed function: RecoveredTextArea
        function RecoveredTextAreaValueChanged(app, event)
            value = app.RecoveredTextArea.Value;
            
        end

        % Value changed function: ExposedTextArea
        function ExposedTextAreaValueChanged(app, event)
            value = app.ExposedTextArea.Value;
            
        end

        % Value changed function: HospitalizedTextArea
        function HospitalizedTextAreaValueChanged(app, event)
            value = app.HospitalizedTextArea.Value;
            
        end

        % Value changed function: DeadTextArea
        function DeadTextAreaValueChanged(app, event)
            value = app.DeadTextArea.Value;
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            if(app.SEIRButton.Value)
                plot(app.UIAxes_2, 0, 0);   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = 32;
                S    = N-E;
                beta_IR = app.BetaSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = 0.062;
                beta_HD = 0.062;
                beta_F  = 0.489;
                theta   = 0.45049;
                alpha   = 0.088883;
                e_1     = 0.066667;
                e_2     = 0.3086;
                k_2     = 0.3086;
                k_1     = 0.07513148;
                pie     = 0.197;
                roe     = 0.06297229;
                delta   = 0.09930487;
                gamma   = 0.4975124;   
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'LEGEND','Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(RMSE);
            end
        
        end

        % Selection changed function: CDCCumulativeDataButtonGroup
        function CDCCumulativeDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.CDCCumulativeDataButtonGroup.SelectedObject;
            
        end

        % Value changed function: NSlider_2
        function NSlider_2ValueChanged(app, event)
            value = app.NSlider_2.Value;
            
        end

        % Value changed function: BetaSlider_2
        function BetaSlider_2ValueChanged(app, event)
            value = app.BetaSlider_2.Value;
            
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            plot(app.UIAxes_2, 0, 0);
            legend(app.UIAxes_2, 'No Data');
        end

        % Value changed function: RMSETextArea
        function RMSETextAreaValueChanged(app, event)
            value = app.RMSETextArea.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CompartmentalModelTab = uitab(app.TabGroup);
            app.CompartmentalModelTab.Title = 'Compartmental Model';
            app.CompartmentsButtonGroup = uibuttongroup(app.CompartmentalModelTab);
            app.CompartmentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Compartments, true);
            app.CompartmentsButtonGroup.Title = 'Compartments';
            app.SEIRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRButton.Text = 'SEIR';
            app.SEIRButton.Value = true;
            app.SEIHRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRButton.Text = 'SEIHR';
            app.SEIRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRDButton.Text = 'SEIRD';
            app.SEIHRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRDButton.Text = 'SEIHRD';
            app.SEIHRFDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRFDButton.Text = 'SEIHRFD';
            app.FINISHButton = uibutton(app.CompartmentalModelTab, 'push');
            app.FINISHButton.ButtonPushedFcn = createCallbackFcn(app, @FINISHButtonPushed, true);
            app.FINISHButton.Text = 'FINISH';
            app.UIAxes = uiaxes(app.CompartmentalModelTab);
            title(app.UIAxes, 'Compartmental Model')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Number of people')
            app.INPUTPARAMETERSLabel = uilabel(app.CompartmentalModelTab);
            app.INPUTPARAMETERSLabel.Text = 'INPUT PARAMETERS';
            app.CONTROLSLabel = uilabel(app.CompartmentalModelTab);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.MODELSLabel = uilabel(app.CompartmentalModelTab);
            app.MODELSLabel.Text = 'MODELS';
            app.PLOTLabel = uilabel(app.CompartmentalModelTab);
            app.PLOTLabel.Text = 'PLOT';
            app.CLEARButton = uibutton(app.CompartmentalModelTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.STARTButton = uibutton(app.CompartmentalModelTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NSliderLabel = uilabel(app.CompartmentalModelTab);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.CompartmentalModelTab);
            app.NSlider.Limits = [0 30000];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @N, true);
            app.NSlider.Value = 3900;
            app.BetaSliderLabel = uilabel(app.CompartmentalModelTab);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.CompartmentalModelTab);
            app.BetaSlider.Limits = [0 1];
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @Beta, true);
            app.BetaSlider.Value = 0.23;
            app.TimedaysKnobLabel = uilabel(app.CompartmentalModelTab);
            app.TimedaysKnobLabel.Text = 'Time (days)';
            app.TimedaysKnob = uiknob(app.CompartmentalModelTab, 'continuous');
            app.TimedaysKnob.Limits = [0 250];
            app.TimedaysKnob.ValueChangedFcn = createCallbackFcn(app, @TimedaysKnobValueChanged, true);
            app.SusceptiblesTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.SusceptiblesTextAreaLabel.Text = 'Susceptibles';
            app.SusceptiblesTextArea = uitextarea(app.CompartmentalModelTab);
            app.SusceptiblesTextArea.ValueChangedFcn = createCallbackFcn(app, @SusceptiblesTextAreaValueChanged, true);
            app.FuneralTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.FuneralTextAreaLabel.Text = 'Funeral';
            app.FuneralTextArea = uitextarea(app.CompartmentalModelTab);
            app.FuneralTextArea.ValueChangedFcn = createCallbackFcn(app, @FuneralTextAreaValueChanged, true);
            app.InfectedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.InfectedTextAreaLabel.Text = 'Infected';
            app.InfectedTextArea = uitextarea(app.CompartmentalModelTab);
            app.InfectedTextArea.ValueChangedFcn = createCallbackFcn(app, @InfectedTextAreaValueChanged, true);
            app.RecoveredTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.RecoveredTextAreaLabel.Text = 'Recovered';
            app.RecoveredTextArea = uitextarea(app.CompartmentalModelTab);
            app.RecoveredTextArea.ValueChangedFcn = createCallbackFcn(app, @RecoveredTextAreaValueChanged, true);
            app.ExposedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.ExposedTextAreaLabel.Text = 'Exposed';
            app.ExposedTextArea = uitextarea(app.CompartmentalModelTab);
            app.ExposedTextArea.ValueChangedFcn = createCallbackFcn(app, @ExposedTextAreaValueChanged, true);
            app.HospitalizedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.HospitalizedTextAreaLabel.Text = 'Hospitalized';
            app.HospitalizedTextArea = uitextarea(app.CompartmentalModelTab);
            app.HospitalizedTextArea.ValueChangedFcn = createCallbackFcn(app, @HospitalizedTextAreaValueChanged, true);
            app.DeadTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.DeadTextAreaLabel.Text = 'Dead';
            app.DeadTextArea = uitextarea(app.CompartmentalModelTab);
            app.DeadTextArea.ValueChangedFcn = createCallbackFcn(app, @DeadTextAreaValueChanged, true);
            app.AgentbasedModelTab = uitab(app.TabGroup);
            app.AgentbasedModelTab.Title = 'Agent-based Model';
            app.HybridModelTab = uitab(app.TabGroup);
            app.HybridModelTab.Title = 'Hybrid Model';
            app.ValidationTab = uitab(app.TabGroup);
            app.ValidationTab.Title = 'Validation';
            app.CompartmentsButtonGroup_2 = uibuttongroup(app.ValidationTab);
            app.CompartmentsButtonGroup_2.Title = 'Compartments';
            app.SEIRButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIRButton_2.Text = 'SEIR';
            app.SEIRButton_2.Value = true;
            app.SEIHRButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRButton_2.Text = 'SEIHR';
            app.SEIRDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIRDButton_2.Text = 'SEIRD';
            app.SEIHRDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRDButton_2.Text = 'SEIHRD';
            app.SEIHRFDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRFDButton_2.Text = 'SEIHRFD';
            app.UIAxes_2 = uiaxes(app.ValidationTab);
            title(app.UIAxes_2, 'Model vs. Reality')
            xlabel(app.UIAxes_2, 'Time (days)')
            ylabel(app.UIAxes_2, 'Number of people')
            app.INPUTPARAMETERSLabel_2 = uilabel(app.ValidationTab);
            app.INPUTPARAMETERSLabel_2.Text = 'INPUT PARAMETERS';
            app.CONTROLSLabel_2 = uilabel(app.ValidationTab);
            app.CONTROLSLabel_2.Text = 'CONTROLS';
            app.MODELSLabel_2 = uilabel(app.ValidationTab);
            app.MODELSLabel_2.Text = 'MODELS';
            app.PLOTLabel_2 = uilabel(app.ValidationTab);
            app.PLOTLabel_2.Text = 'PLOT';
            app.CLEARButton_2 = uibutton(app.ValidationTab, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.NSlider_2Label = uilabel(app.ValidationTab);
            app.NSlider_2Label.Text = 'N';
            app.NSlider_2 = uislider(app.ValidationTab);
            app.NSlider_2.Limits = [0 30000];
            app.NSlider_2.ValueChangedFcn = createCallbackFcn(app, @NSlider_2ValueChanged, true);
            app.BetaSlider_2Label = uilabel(app.ValidationTab);
            app.BetaSlider_2Label.Text = 'Beta';
            app.BetaSlider_2 = uislider(app.ValidationTab);
            app.BetaSlider_2.Limits = [0 1];
            app.BetaSlider_2.ValueChangedFcn = createCallbackFcn(app, @BetaSlider_2ValueChanged, true);
            app.STARTButton_2 = uibutton(app.ValidationTab, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.LOADDATALabel = uilabel(app.ValidationTab);
            app.LOADDATALabel.Text = 'LOAD DATA';
            app.CDCCumulativeDataButtonGroup = uibuttongroup(app.ValidationTab);
            app.CDCCumulativeDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CDCCumulativeDataButtonGroupSelectionChanged, true);
            app.CDCCumulativeDataButtonGroup.Title = 'CDC Cumulative Data';
            app.Liberia2014Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Liberia2014Button.Text = 'Liberia 2014';
            app.Liberia2014Button.Value = true;
            app.Liberia2015Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Liberia2015Button.Text = 'Liberia 2015';
            app.Liberia2016Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Liberia2016Button.Text = 'Liberia 2016';
            app.Guinea2014Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Guinea2014Button.Text = 'Guinea 2014';
            app.Guinea2015Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Guinea2015Button.Text = 'Guinea 2015';
            app.Guinea2016Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Guinea2016Button.Text = 'Guinea 2016';
            app.SierraLeone2014Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.SierraLeone2014Button.Text = 'Sierra Leone 2014';
            app.SierraLeone2015Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.SierraLeone2015Button.Text = 'Sierra Leone 2015';
            app.SierraLeone2016Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.SierraLeone2016Button.Text = 'Sierra Leone 2016';
            app.RMSETextAreaLabel = uilabel(app.ValidationTab);
            app.RMSETextAreaLabel.Text = 'RMSE';
            app.RMSETextArea = uitextarea(app.ValidationTab);
            app.RMSETextArea.ValueChangedFcn = createCallbackFcn(app, @RMSETextAreaValueChanged, true);
            app.ERRORLabel = uilabel(app.ValidationTab);
            app.ERRORLabel.Text = 'ERROR';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end