classdef GUI_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CompartmentalModelTab           matlab.ui.container.Tab
        CompartmentsButtonGroup         matlab.ui.container.ButtonGroup
        SEIRButton                      matlab.ui.control.RadioButton
        SEIHRButton                     matlab.ui.control.RadioButton
        SEIRDButton                     matlab.ui.control.RadioButton
        SEIHRDButton                    matlab.ui.control.RadioButton
        SEIHRFDButton                   matlab.ui.control.RadioButton
        FINISHButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        INPUTPARAMETERSLabel            matlab.ui.control.Label
        CONTROLSLabel                   matlab.ui.control.Label
        MODELSLabel                     matlab.ui.control.Label
        PLOTLabel                       matlab.ui.control.Label
        CLEARButton                     matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        NTotalPopulationSlider          matlab.ui.control.Slider
        BetaCommunityContactRateSlider  matlab.ui.control.Slider
        TimedaysKnobLabel               matlab.ui.control.Label
        TimedaysKnob                    matlab.ui.control.Knob
        SusceptiblesTextAreaLabel       matlab.ui.control.Label
        SusceptiblesTextArea            matlab.ui.control.TextArea
        FuneralTextAreaLabel            matlab.ui.control.Label
        FuneralTextArea                 matlab.ui.control.TextArea
        InfectedTextAreaLabel           matlab.ui.control.Label
        InfectedTextArea                matlab.ui.control.TextArea
        RecoveredTextAreaLabel          matlab.ui.control.Label
        RecoveredTextArea               matlab.ui.control.TextArea
        ExposedTextAreaLabel            matlab.ui.control.Label
        ExposedTextArea                 matlab.ui.control.TextArea
        HospitalizedTextAreaLabel       matlab.ui.control.Label
        HospitalizedTextArea            matlab.ui.control.TextArea
        DeadTextAreaLabel               matlab.ui.control.Label
        DeadTextArea                    matlab.ui.control.TextArea
        MODELSPECIFICPARAMETERSLabel    matlab.ui.control.Label
        SEIRPanel                       matlab.ui.container.Panel
        AlphaIncubationPeriodSliderLabel  matlab.ui.control.Label
        AlphaIncubationPeriodSlider     matlab.ui.control.Slider
        ThetaProbabilityofSurvivalSliderLabel  matlab.ui.control.Label
        ThetaProbabilityofSurvivalSlider  matlab.ui.control.Slider
        PiProbabilityofSeekingHospitalizationSliderLabel  matlab.ui.control.Label
        PiProbabilityofSeekingHospitalizationSlider  matlab.ui.control.Slider
        e1TimefromInfectiontoRecoverySliderLabel  matlab.ui.control.Label
        e1TimefromInfectiontoRecoverySlider  matlab.ui.control.Slider
        e2k2TimeuntilHospitalizationSliderLabel  matlab.ui.control.Label
        e2k2TimeuntilHospitalizationSlider  matlab.ui.control.Slider
        k1TimefromInfectiontoDeathSliderLabel  matlab.ui.control.Label
        k1TimefromInfectiontoDeathSlider  matlab.ui.control.Slider
        NTotalPopulationSlider_2Label_2  matlab.ui.control.Label
        BetaCommunityContactRateSlider_2Label_2  matlab.ui.control.Label
        HPanel                          matlab.ui.container.Panel
        BetaHHospitalContactRateSliderLabel  matlab.ui.control.Label
        BetaHHospitalContactRateSlider  matlab.ui.control.Slider
        DeltaTimefromHospitalizationtoDeathSliderLabel  matlab.ui.control.Label
        DeltaTimefromHospitalizationtoDeathSlider  matlab.ui.control.Slider
        RoeTimefromHospitalizationtoRecoverySliderLabel  matlab.ui.control.Label
        RoeTimefromHospitalizationtoRecoverySlider  matlab.ui.control.Slider
        DPanel                          matlab.ui.container.Panel
        GammaDurationofBurialTimeSliderLabel  matlab.ui.control.Label
        GammaDurationofBurialTimeSlider  matlab.ui.control.Slider
        FPanel                          matlab.ui.container.Panel
        BetaFFuneralContactRateSliderLabel  matlab.ui.control.Label
        BetaFFuneralContactRateSlider   matlab.ui.control.Slider
        INITIALCONDITIONSLabel          matlab.ui.control.Label
        EKnobLabel                      matlab.ui.control.Label
        EKnob                           matlab.ui.control.Knob
        AgentbasedModelTab              matlab.ui.container.Tab
        HybridModelTab                  matlab.ui.container.Tab
        ValidationTab                   matlab.ui.container.Tab
        CompartmentsButtonGroup_2       matlab.ui.container.ButtonGroup
        SEIRButton_2                    matlab.ui.control.RadioButton
        SEIHRButton_2                   matlab.ui.control.RadioButton
        SEIRDButton_2                   matlab.ui.control.RadioButton
        SEIHRDButton_2                  matlab.ui.control.RadioButton
        SEIHRFDButton_2                 matlab.ui.control.RadioButton
        UIAxes_2                        matlab.ui.control.UIAxes
        INPUTPARAMETERSLabel_2          matlab.ui.control.Label
        CONTROLSLabel_2                 matlab.ui.control.Label
        MODELSLabel_2                   matlab.ui.control.Label
        PLOTLabel_2                     matlab.ui.control.Label
        CLEARButton_2                   matlab.ui.control.Button
        NTotalPopulationSlider_2Label   matlab.ui.control.Label
        NTotalPopulationSlider_2        matlab.ui.control.Slider
        BetaCommunityContactRateSlider_2  matlab.ui.control.Slider
        STARTButton_2                   matlab.ui.control.Button
        LOADDATALabel                   matlab.ui.control.Label
        CDCCumulativeDataButtonGroup    matlab.ui.container.ButtonGroup
        Liberia2014Button               matlab.ui.control.RadioButton
        Liberia2015Button               matlab.ui.control.RadioButton
        Liberia2016Button               matlab.ui.control.RadioButton
        Guinea2014Button                matlab.ui.control.RadioButton
        Guinea2015Button                matlab.ui.control.RadioButton
        Guinea2016Button                matlab.ui.control.RadioButton
        SierraLeone2014Button           matlab.ui.control.RadioButton
        SierraLeone2015Button           matlab.ui.control.RadioButton
        SierraLeone2016Button           matlab.ui.control.RadioButton
        RMSETextAreaLabel               matlab.ui.control.Label
        RMSETextArea                    matlab.ui.control.TextArea
        ERRORLabel                      matlab.ui.control.Label
        BetaCommunityContactRateSlider_2Label_3  matlab.ui.control.Label
        MODELSPECIFICPARAMETERSLabel_2  matlab.ui.control.Label
        SEIRPanel_2                     matlab.ui.container.Panel
        AlphaIncubationPeriodSlider_2Label  matlab.ui.control.Label
        AlphaIncubationPeriodSlider_2   matlab.ui.control.Slider
        ThetaProbabilityofSurvivalSlider_2Label  matlab.ui.control.Label
        ThetaProbabilityofSurvivalSlider_2  matlab.ui.control.Slider
        PiProbabilityofSeekingHospitalizationSlider_2Label  matlab.ui.control.Label
        PiProbabilityofSeekingHospitalizationSlider_2  matlab.ui.control.Slider
        e1TimefromInfectiontoRecoverySlider_2Label  matlab.ui.control.Label
        e1TimefromInfectiontoRecoverySlider_2  matlab.ui.control.Slider
        e2k2TimeuntilHospitalizationSlider_2Label  matlab.ui.control.Label
        e2k2TimeuntilHospitalizationSlider_2  matlab.ui.control.Slider
        k1TimefromInfectiontoDeathSlider_2Label  matlab.ui.control.Label
        k1TimefromInfectiontoDeathSlider_2  matlab.ui.control.Slider
        HPanel_2                        matlab.ui.container.Panel
        BetaHHospitalContactRateSlider_2Label  matlab.ui.control.Label
        BetaHHospitalContactRateSlider_2  matlab.ui.control.Slider
        DeltaTimefromHospitalizationtoDeathSlider_2Label  matlab.ui.control.Label
        DeltaTimefromHospitalizationtoDeathSlider_2  matlab.ui.control.Slider
        RoeTimefromHospitalizationtoRecoverySlider_2Label  matlab.ui.control.Label
        RoeTimefromHospitalizationtoRecoverySlider_2  matlab.ui.control.Slider
        DPanel_2                        matlab.ui.container.Panel
        GammaDurationofBurialTimeSlider_2Label  matlab.ui.control.Label
        GammaDurationofBurialTimeSlider_2  matlab.ui.control.Slider
        FPanel_2                        matlab.ui.container.Panel
        BetaFFuneralContactRateSlider_2Label  matlab.ui.control.Label
        BetaFFuneralContactRateSlider_2  matlab.ui.control.Slider
        INITIALCONDITIONSLabel_2        matlab.ui.control.Label
        EKnob_2Label                    matlab.ui.control.Label
        EKnob_2                         matlab.ui.control.Knob
    end

    methods (Access = private)

        % Button pushed function: FINISHButton
        function FINISHButtonPushed(app, event)
            if(app.SEIRButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                
            end 
            
            if(app.SEIHRButton.Value)
                plot(app.UIAxes, 0, 0);        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];

            end 
            
            if(app.SEIRDButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIHRDButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');    
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),7)];
            end 
            
            if(app.SEIHRFDButton.Value)
                plot(app.UIAxes, 0, 0);   
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;
                
                
                tspan = 1:1:254;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.FuneralTextArea.Value = [mat2str(xa(length(tspan)),7)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),8)];                
            end 
                
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            plot(app.UIAxes, 0, 0);
            legend(app.UIAxes, 'No Data');
            
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);  
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.SEIRButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered');
                hold(app.UIAxes, 'off');
                
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
            end 
            
            if(app.SEIHRButton.Value)
                plot(app.UIAxes, 0, 0);       
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;   
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered');
                hold(app.UIAxes, 'off');

                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIRDButton.Value)
                plot(app.UIAxes, 0, 0);     
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,6),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Recovered','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),6)];
            end 
            
            if(app.SEIHRDButton.Value)
                plot(app.UIAxes, 0, 0);        
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;    
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Deceased');
                hold(app.UIAxes, 'off');      
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),7)];

            end 
            
            if(app.SEIHRFDButton.Value)
                plot(app.UIAxes, 0, 0);      
                app.SusceptiblesTextArea.Value = num2str(0);
                app.ExposedTextArea.Value = num2str(0);
                app.InfectedTextArea.Value = num2str(0);
                app.HospitalizedTextArea.Value = num2str(0);
                app.RecoveredTextArea.Value = num2str(0);
                app.FuneralTextArea.Value = num2str(0);
                app.DeadTextArea.Value = num2str(0);
                
                N    = app.NTotalPopulationSlider.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider.Value;
                beta_HD = app.BetaHHospitalContactRateSlider.Value;
                beta_F  = app.BetaFFuneralContactRateSlider.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider.Value;
                
                
                tspan = 1:1:app.TimedaysKnob.Value;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,xa(:,1),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,2),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,3)+xa(:,4),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,5)+xa(:,6),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,7),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,8),'LineWidth',3);
                plot(app.UIAxes,t,xa(:,9),'LineWidth',3);
                legend(app.UIAxes, 'LEGEND','Susceptibles','Exposed','Infectious','Hospitalized','Recovered','Funeral','Deceased');
                hold(app.UIAxes, 'off');
                
                app.SusceptiblesTextArea.Value = [mat2str(xa(length(tspan)),1)];
                app.ExposedTextArea.Value = [mat2str(xa(length(tspan)),2)];
                app.InfectedTextArea.Value = [mat2str(xa(length(tspan),3)+xa(length(tspan)),4)];
                app.HospitalizedTextArea.Value = [mat2str(xa(length(tspan)),5)];
                app.RecoveredTextArea.Value = [mat2str(xa(length(tspan)),6)];
                app.FuneralTextArea.Value = [mat2str(xa(length(tspan)),7)];
                app.DeadTextArea.Value = [mat2str(xa(length(tspan)),8)];
            end 
        end

        % Value changed function: TimedaysKnob
        function TimedaysKnobValueChanged(app, event)
            value = app.TimedaysKnob.Value;
        end

        % Value changed function: SusceptiblesTextArea
        function SusceptiblesTextAreaValueChanged(app, event)
            value = app.SusceptiblesTextArea.Value;
        end

        % Selection changed function: CompartmentsButtonGroup
        function Compartments(app, event)
            selectedButton = app.CompartmentsButtonGroup.SelectedObject;
            
        end

        % Value changed function: NTotalPopulationSlider
        function N(app, event)
            value = app.NTotalPopulationSlider.Value;
            
        end

        % Value changed function: BetaCommunityContactRateSlider
        function Beta(app, event)
            value = app.BetaCommunityContactRateSlider.Value;
            
        end

        % Value changed function: FuneralTextArea
        function FuneralTextAreaValueChanged(app, event)
            value = app.FuneralTextArea.Value;
            
        end

        % Value changed function: InfectedTextArea
        function InfectedTextAreaValueChanged(app, event)
            value = app.InfectedTextArea.Value;
            
        end

        % Value changed function: RecoveredTextArea
        function RecoveredTextAreaValueChanged(app, event)
            value = app.RecoveredTextArea.Value;
            
        end

        % Value changed function: ExposedTextArea
        function ExposedTextAreaValueChanged(app, event)
            value = app.ExposedTextArea.Value;
            
        end

        % Value changed function: HospitalizedTextArea
        function HospitalizedTextAreaValueChanged(app, event)
            value = app.HospitalizedTextArea.Value;
            
        end

        % Value changed function: DeadTextArea
        function DeadTextAreaValueChanged(app, event)
            value = app.DeadTextArea.Value;
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            if(app.SEIRButton_2.Value)
                plot(app.UIAxes_2, 0, 0);   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'LEGEND','Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(RMSE);
            end
            
            if(app.SEIHRButton_2.Value)
                plot(app.UIAxes_2, 0, 0);   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                 e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'LEGEND','Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(RMSE);
            end
            
            if(app.SEIRDButton_2.Value)
                plot(app.UIAxes_2, 0, 0);   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            (1-pie)*e_1*x(3);
                            ((1-pie)*k_1*x(4))*gamma];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'LEGEND','Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(RMSE);
            end
         
            if(app.SEIHRDButton_2.Value)
                plot(app.UIAxes_2, 0, 0);   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'LEGEND','Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(RMSE);
            end                        

            if(app.SEIHRFDButton_2.Value)
                plot(app.UIAxes_2, 0, 0);   
                app.RMSETextArea.Value = num2str(0);
                
                [filename, filepath, ~] = uigetfile('*.xlsx');
                [~, headers, ~] = xlsread([filepath, filename], 1, '1:1');
                
                tt = transpose(xlsread([filepath, filename], 1, 'A:A'));%day 0 is 25/3/2014
                I_real = transpose(xlsread([filepath, filename], 1, 'E:E'));
                D_real = transpose(xlsread([filepath, filename], 1, 'F:F'));
                
                N    = app.NTotalPopulationSlider_2.Value;          %fit is 3900, normal is 1500000
                E    = app.EKnob_2.Value;
                S    = N-E;
                beta_IR = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_ID = app.BetaCommunityContactRateSlider_2.Value;         %fit is 0.230, normal is 0.160
                beta_HR = app.BetaHHospitalContactRateSlider_2.Value;
                beta_HD = app.BetaHHospitalContactRateSlider_2.Value;
                beta_F  = app.BetaFFuneralContactRateSlider_2.Value;
                theta   = (1-app.ThetaProbabilityofSurvivalSlider_2.Value);
                alpha   = 1/app.AlphaIncubationPeriodSlider_2.Value;
                e_1     = 1/app.e1TimefromInfectiontoRecoverySlider_2.Value;
                e_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_2     = 1/app.e2k2TimeuntilHospitalizationSlider_2.Value;
                k_1     = 1/app.k1TimefromInfectiontoDeathSlider_2.Value;
                pie     = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
                roe     = 1/app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
                delta   = 1/app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
                gamma   = 1/app.GammaDurationofBurialTimeSlider_2.Value; 
                
                tspan = tt;
                f = @(t,x) [-(1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8));
                            (1/N)*(beta_IR*x(1)*x(3)+beta_ID*x(1)*x(4)+beta_HR*x(1)*x(5)+beta_HD*x(1)*x(6)+beta_F*x(1)*x(8))-alpha*x(2);
                            (1-theta)*alpha*x(2)-(1-pie)*e_1*x(3)-pie*e_2*x(3);
                            theta*alpha*x(2)-(1-pie)*k_1*x(4)-pie*k_2*x(4);
                            pie*e_2*x(3)-roe*x(5);
                            pie*k_2*x(4)-delta*x(6);
                            (1-pie)*e_1*x(3)+roe*x(5);
                            (1-pie)*k_1*x(4)-gamma*x(8);
                            gamma*x(8)+delta*x(6)];
                [t,xa]=ode45(f,tspan, [S E 0 0 0 0 0 0 0]);
                
                I_eqn   = cumsum(xa(:,3)+xa(:,4));
                
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2,tt,I_eqn,'LineWidth',3);
                scatter(app.UIAxes_2,tt,I_real);
                legend(app.UIAxes_2, 'LEGEND','Model','CDC Data');
                hold(app.UIAxes_2, 'off');
                
                RMSE = (immse(I_real, transpose(I_eqn)))^0.5;
                app.RMSETextArea.Value = num2str(RMSE);
            end                        
            
        end

        % Selection changed function: CDCCumulativeDataButtonGroup
        function CDCCumulativeDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.CDCCumulativeDataButtonGroup.SelectedObject;
            
        end

        % Value changed function: NTotalPopulationSlider_2
        function NTotalPopulationSlider_2ValueChanged(app, event)
            value = app.NTotalPopulationSlider_2.Value;
            
        end

        % Value changed function: BetaCommunityContactRateSlider_2
        function BetaCommunityContactRateSlider_2ValueChanged(app, event)
            value = app.BetaCommunityContactRateSlider_2.Value;
            
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            plot(app.UIAxes_2, 0, 0);
            legend(app.UIAxes_2, 'No Data');
        end

        % Value changed function: RMSETextArea
        function RMSETextAreaValueChanged(app, event)
            value = app.RMSETextArea.Value;
            
        end

        % Value changed function: AlphaIncubationPeriodSlider
        function AlphaIncubationPeriodSliderValueChanged(app, event)
            value = app.AlphaIncubationPeriodSlider.Value;
        end

        % Value changed function: ThetaProbabilityofSurvivalSlider
        function ThetaProbabilityofSurvivalSliderValueChanged(app, event)
            value = app.ThetaProbabilityofSurvivalSlider.Value;
            
        end

        % Value changed function: 
        % PiProbabilityofSeekingHospitalizationSlider
        function PiProbabilityofSeekingHospitalizationSliderValueChanged(app, event)
            value = app.PiProbabilityofSeekingHospitalizationSlider.Value;
            
        end

        % Value changed function: e1TimefromInfectiontoRecoverySlider
        function e1TimefromInfectiontoRecoverySliderValueChanged(app, event)
            value = app.e1TimefromInfectiontoRecoverySlider.Value;
            
        end

        % Value changed function: e2k2TimeuntilHospitalizationSlider
        function e2k2TimeuntilHospitalizationSliderValueChanged(app, event)
            value = app.e2k2TimeuntilHospitalizationSlider.Value;
            
        end

        % Value changed function: k1TimefromInfectiontoDeathSlider
        function k1TimefromInfectiontoDeathSliderValueChanged(app, event)
            value = app.k1TimefromInfectiontoDeathSlider.Value;
            
        end

        % Value changed function: BetaHHospitalContactRateSlider
        function BetaHHospitalContactRateSliderValueChanged(app, event)
            value = app.BetaHHospitalContactRateSlider.Value;
            
        end

        % Value changing function: 
        % RoeTimefromHospitalizationtoRecoverySlider
        function RoeTimefromHospitalizationtoRecoverySliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: 
        % RoeTimefromHospitalizationtoRecoverySlider
        function RoeTimefromHospitalizationtoRecoverySliderValueChanged(app, event)
            value = app.RoeTimefromHospitalizationtoRecoverySlider.Value;
            
        end

        % Value changed function: 
        % DeltaTimefromHospitalizationtoDeathSlider
        function DeltaTimefromHospitalizationtoDeathSliderValueChanged(app, event)
            value = app.DeltaTimefromHospitalizationtoDeathSlider.Value;
            
        end

        % Value changed function: GammaDurationofBurialTimeSlider
        function GammaDurationofBurialTimeSliderValueChanged(app, event)
            value = app.GammaDurationofBurialTimeSlider.Value;
            
        end

        % Value changed function: BetaFFuneralContactRateSlider
        function BetaFFuneralContactRateSliderValueChanged(app, event)
            value = app.BetaFFuneralContactRateSlider.Value;
            
        end

        % Value changed function: EKnob
        function EKnobValueChanged(app, event)
            value = app.EKnob.Value;
            
        end

        % Value changed function: AlphaIncubationPeriodSlider_2
        function AlphaIncubationPeriodSlider_2ValueChanged(app, event)
            value = app.AlphaIncubationPeriodSlider_2.Value;
            
        end

        % Value changed function: ThetaProbabilityofSurvivalSlider_2
        function ThetaProbabilityofSurvivalSlider_2ValueChanged(app, event)
            value = app.ThetaProbabilityofSurvivalSlider_2.Value;
            
        end

        % Value changed function: 
        % PiProbabilityofSeekingHospitalizationSlider_2
        function PiProbabilityofSeekingHospitalizationSlider_2ValueChanged(app, event)
            value = app.PiProbabilityofSeekingHospitalizationSlider_2.Value;
            
        end

        % Value changed function: 
        % e1TimefromInfectiontoRecoverySlider_2
        function e1TimefromInfectiontoRecoverySlider_2ValueChanged(app, event)
            value = app.e1TimefromInfectiontoRecoverySlider_2.Value;
            
        end

        % Value changed function: e2k2TimeuntilHospitalizationSlider_2
        function e2k2TimeuntilHospitalizationSlider_2ValueChanged(app, event)
            value = app.e2k2TimeuntilHospitalizationSlider_2.Value;
            
        end

        % Value changed function: k1TimefromInfectiontoDeathSlider_2
        function k1TimefromInfectiontoDeathSlider_2ValueChanged(app, event)
            value = app.k1TimefromInfectiontoDeathSlider_2.Value;
            
        end

        % Value changed function: BetaHHospitalContactRateSlider_2
        function BetaHHospitalContactRateSlider_2ValueChanged(app, event)
            value = app.BetaHHospitalContactRateSlider_2.Value;
            
        end

        % Value changed function: 
        % RoeTimefromHospitalizationtoRecoverySlider_2
        function RoeTimefromHospitalizationtoRecoverySlider_2ValueChanged(app, event)
            value = app.RoeTimefromHospitalizationtoRecoverySlider_2.Value;
            
        end

        % Value changed function: 
        % DeltaTimefromHospitalizationtoDeathSlider_2
        function DeltaTimefromHospitalizationtoDeathSlider_2ValueChanged(app, event)
            value = app.DeltaTimefromHospitalizationtoDeathSlider_2.Value;
            
        end

        % Value changed function: GammaDurationofBurialTimeSlider_2
        function GammaDurationofBurialTimeSlider_2ValueChanged(app, event)
            value = app.GammaDurationofBurialTimeSlider_2.Value;
            
        end

        % Value changed function: BetaFFuneralContactRateSlider_2
        function BetaFFuneralContactRateSlider_2ValueChanged(app, event)
            value = app.BetaFFuneralContactRateSlider_2.Value;
            
        end

        % Value changed function: EKnob_2
        function EKnob_2ValueChanged(app, event)
            value = app.EKnob_2.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CompartmentalModelTab = uitab(app.TabGroup);
            app.CompartmentalModelTab.Title = 'Compartmental Model';
            app.CompartmentsButtonGroup = uibuttongroup(app.CompartmentalModelTab);
            app.CompartmentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Compartments, true);
            app.CompartmentsButtonGroup.Title = 'Compartments';
            app.SEIRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRButton.Text = 'SEIR';
            app.SEIRButton.Value = true;
            app.SEIHRButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRButton.Text = 'SEIHR';
            app.SEIRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIRDButton.Text = 'SEIRD';
            app.SEIHRDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRDButton.Text = 'SEIHRD';
            app.SEIHRFDButton = uiradiobutton(app.CompartmentsButtonGroup);
            app.SEIHRFDButton.Text = 'SEIHRFD';
            app.FINISHButton = uibutton(app.CompartmentalModelTab, 'push');
            app.FINISHButton.ButtonPushedFcn = createCallbackFcn(app, @FINISHButtonPushed, true);
            app.FINISHButton.Text = 'FINISH';
            app.UIAxes = uiaxes(app.CompartmentalModelTab);
            title(app.UIAxes, 'Compartmental Model')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Number of people')
            app.INPUTPARAMETERSLabel = uilabel(app.CompartmentalModelTab);
            app.INPUTPARAMETERSLabel.Text = 'INPUT PARAMETERS';
            app.CONTROLSLabel = uilabel(app.CompartmentalModelTab);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.MODELSLabel = uilabel(app.CompartmentalModelTab);
            app.MODELSLabel.Text = 'MODELS';
            app.PLOTLabel = uilabel(app.CompartmentalModelTab);
            app.PLOTLabel.Text = 'PLOT';
            app.CLEARButton = uibutton(app.CompartmentalModelTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.STARTButton = uibutton(app.CompartmentalModelTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NTotalPopulationSlider = uislider(app.CompartmentalModelTab);
            app.NTotalPopulationSlider.Limits = [0 30000];
            app.NTotalPopulationSlider.MajorTicks = [0 10000 20000 30000];
            app.NTotalPopulationSlider.ValueChangedFcn = createCallbackFcn(app, @N, true);
            app.NTotalPopulationSlider.Value = 3900;
            app.BetaCommunityContactRateSlider = uislider(app.CompartmentalModelTab);
            app.BetaCommunityContactRateSlider.Limits = [0 1];
            app.BetaCommunityContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @Beta, true);
            app.BetaCommunityContactRateSlider.Value = 0.23;
            app.TimedaysKnobLabel = uilabel(app.CompartmentalModelTab);
            app.TimedaysKnobLabel.Text = 'Time (days)';
            app.TimedaysKnob = uiknob(app.CompartmentalModelTab, 'continuous');
            app.TimedaysKnob.Limits = [0 250];
            app.TimedaysKnob.ValueChangedFcn = createCallbackFcn(app, @TimedaysKnobValueChanged, true);
            app.SusceptiblesTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.SusceptiblesTextAreaLabel.Text = 'Susceptibles';
            app.SusceptiblesTextArea = uitextarea(app.CompartmentalModelTab);
            app.SusceptiblesTextArea.ValueChangedFcn = createCallbackFcn(app, @SusceptiblesTextAreaValueChanged, true);
            app.FuneralTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.FuneralTextAreaLabel.Text = 'Funeral';
            app.FuneralTextArea = uitextarea(app.CompartmentalModelTab);
            app.FuneralTextArea.ValueChangedFcn = createCallbackFcn(app, @FuneralTextAreaValueChanged, true);
            app.InfectedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.InfectedTextAreaLabel.Text = 'Infected';
            app.InfectedTextArea = uitextarea(app.CompartmentalModelTab);
            app.InfectedTextArea.ValueChangedFcn = createCallbackFcn(app, @InfectedTextAreaValueChanged, true);
            app.RecoveredTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.RecoveredTextAreaLabel.Text = 'Recovered';
            app.RecoveredTextArea = uitextarea(app.CompartmentalModelTab);
            app.RecoveredTextArea.ValueChangedFcn = createCallbackFcn(app, @RecoveredTextAreaValueChanged, true);
            app.ExposedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.ExposedTextAreaLabel.Text = 'Exposed';
            app.ExposedTextArea = uitextarea(app.CompartmentalModelTab);
            app.ExposedTextArea.ValueChangedFcn = createCallbackFcn(app, @ExposedTextAreaValueChanged, true);
            app.HospitalizedTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.HospitalizedTextAreaLabel.Text = 'Hospitalized';
            app.HospitalizedTextArea = uitextarea(app.CompartmentalModelTab);
            app.HospitalizedTextArea.ValueChangedFcn = createCallbackFcn(app, @HospitalizedTextAreaValueChanged, true);
            app.DeadTextAreaLabel = uilabel(app.CompartmentalModelTab);
            app.DeadTextAreaLabel.Text = 'Dead';
            app.DeadTextArea = uitextarea(app.CompartmentalModelTab);
            app.DeadTextArea.ValueChangedFcn = createCallbackFcn(app, @DeadTextAreaValueChanged, true);
            app.MODELSPECIFICPARAMETERSLabel = uilabel(app.CompartmentalModelTab);
            app.MODELSPECIFICPARAMETERSLabel.Text = 'MODEL-SPECIFIC PARAMETERS';
            app.SEIRPanel = uipanel(app.CompartmentalModelTab);
            app.SEIRPanel.Title = 'SEIR';
            app.AlphaIncubationPeriodSliderLabel = uilabel(app.SEIRPanel);
            app.AlphaIncubationPeriodSliderLabel.Text = 'Alpha: Incubation Period ';
            app.AlphaIncubationPeriodSlider = uislider(app.SEIRPanel);
            app.AlphaIncubationPeriodSlider.Limits = [2 21];
            app.AlphaIncubationPeriodSlider.MajorTicks = [2 5 8 11 14 17 21];
            app.AlphaIncubationPeriodSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaIncubationPeriodSliderValueChanged, true);
            app.AlphaIncubationPeriodSlider.Value = 12;
            app.ThetaProbabilityofSurvivalSliderLabel = uilabel(app.SEIRPanel);
            app.ThetaProbabilityofSurvivalSliderLabel.Text = 'Theta: Probability of Survival';
            app.ThetaProbabilityofSurvivalSlider = uislider(app.SEIRPanel);
            app.ThetaProbabilityofSurvivalSlider.Limits = [0 1];
            app.ThetaProbabilityofSurvivalSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ThetaProbabilityofSurvivalSlider.ValueChangedFcn = createCallbackFcn(app, @ThetaProbabilityofSurvivalSliderValueChanged, true);
            app.ThetaProbabilityofSurvivalSlider.Value = 0.549;
            app.PiProbabilityofSeekingHospitalizationSliderLabel = uilabel(app.SEIRPanel);
            app.PiProbabilityofSeekingHospitalizationSliderLabel.Text = 'Pi: Probability of Seeking Hospitalization';
            app.PiProbabilityofSeekingHospitalizationSlider = uislider(app.SEIRPanel);
            app.PiProbabilityofSeekingHospitalizationSlider.Limits = [0 1];
            app.PiProbabilityofSeekingHospitalizationSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PiProbabilityofSeekingHospitalizationSlider.ValueChangedFcn = createCallbackFcn(app, @PiProbabilityofSeekingHospitalizationSliderValueChanged, true);
            app.PiProbabilityofSeekingHospitalizationSlider.Value = 0.197;
            app.e1TimefromInfectiontoRecoverySliderLabel = uilabel(app.SEIRPanel);
            app.e1TimefromInfectiontoRecoverySliderLabel.Text = 'e1: Time from Infection to Recovery';
            app.e1TimefromInfectiontoRecoverySlider = uislider(app.SEIRPanel);
            app.e1TimefromInfectiontoRecoverySlider.Limits = [0 20];
            app.e1TimefromInfectiontoRecoverySlider.MajorTicks = [0 5 10 15 20];
            app.e1TimefromInfectiontoRecoverySlider.ValueChangedFcn = createCallbackFcn(app, @e1TimefromInfectiontoRecoverySliderValueChanged, true);
            app.e1TimefromInfectiontoRecoverySlider.Value = 15;
            app.e2k2TimeuntilHospitalizationSliderLabel = uilabel(app.SEIRPanel);
            app.e2k2TimeuntilHospitalizationSliderLabel.Text = 'e2, k2: Time until Hospitalization';
            app.e2k2TimeuntilHospitalizationSlider = uislider(app.SEIRPanel);
            app.e2k2TimeuntilHospitalizationSlider.Limits = [0 5];
            app.e2k2TimeuntilHospitalizationSlider.MajorTicks = [0 1 2 3 4 5];
            app.e2k2TimeuntilHospitalizationSlider.ValueChangedFcn = createCallbackFcn(app, @e2k2TimeuntilHospitalizationSliderValueChanged, true);
            app.e2k2TimeuntilHospitalizationSlider.Value = 3.24;
            app.k1TimefromInfectiontoDeathSliderLabel = uilabel(app.SEIRPanel);
            app.k1TimefromInfectiontoDeathSliderLabel.Text = 'k1: Time from Infection to Death ';
            app.k1TimefromInfectiontoDeathSlider = uislider(app.SEIRPanel);
            app.k1TimefromInfectiontoDeathSlider.Limits = [0 30];
            app.k1TimefromInfectiontoDeathSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.k1TimefromInfectiontoDeathSlider.ValueChangedFcn = createCallbackFcn(app, @k1TimefromInfectiontoDeathSliderValueChanged, true);
            app.k1TimefromInfectiontoDeathSlider.Value = 13.31;
            app.NTotalPopulationSlider_2Label_2 = uilabel(app.CompartmentalModelTab);
            app.NTotalPopulationSlider_2Label_2.Text = 'N: Total Population';
            app.BetaCommunityContactRateSlider_2Label_2 = uilabel(app.CompartmentalModelTab);
            app.BetaCommunityContactRateSlider_2Label_2.Text = 'Beta(C): Community Contact Rate';
            app.HPanel = uipanel(app.CompartmentalModelTab);
            app.HPanel.Title = '+ H';
            app.BetaHHospitalContactRateSliderLabel = uilabel(app.HPanel);
            app.BetaHHospitalContactRateSliderLabel.Text = 'Beta(H): Hospital Contact Rate';
            app.BetaHHospitalContactRateSlider = uislider(app.HPanel);
            app.BetaHHospitalContactRateSlider.Limits = [0 0.1];
            app.BetaHHospitalContactRateSlider.MajorTicks = [0 0.025 0.05 0.075 0.1];
            app.BetaHHospitalContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @BetaHHospitalContactRateSliderValueChanged, true);
            app.BetaHHospitalContactRateSlider.Value = 0.062;
            app.DeltaTimefromHospitalizationtoDeathSliderLabel = uilabel(app.HPanel);
            app.DeltaTimefromHospitalizationtoDeathSliderLabel.Text = 'Delta: Time from Hospitalization to Death ';
            app.DeltaTimefromHospitalizationtoDeathSlider = uislider(app.HPanel);
            app.DeltaTimefromHospitalizationtoDeathSlider.Limits = [0 20];
            app.DeltaTimefromHospitalizationtoDeathSlider.MajorTicks = [0 4 8 12 16 20];
            app.DeltaTimefromHospitalizationtoDeathSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaTimefromHospitalizationtoDeathSliderValueChanged, true);
            app.DeltaTimefromHospitalizationtoDeathSlider.Value = 10.07;
            app.RoeTimefromHospitalizationtoRecoverySliderLabel = uilabel(app.HPanel);
            app.RoeTimefromHospitalizationtoRecoverySliderLabel.Text = 'Roe: Time from Hospitalization to Recovery ';
            app.RoeTimefromHospitalizationtoRecoverySlider = uislider(app.HPanel);
            app.RoeTimefromHospitalizationtoRecoverySlider.Limits = [0 20];
            app.RoeTimefromHospitalizationtoRecoverySlider.MajorTicks = [0 4 8 12 16 20];
            app.RoeTimefromHospitalizationtoRecoverySlider.ValueChangedFcn = createCallbackFcn(app, @RoeTimefromHospitalizationtoRecoverySliderValueChanged, true);
            app.RoeTimefromHospitalizationtoRecoverySlider.ValueChangingFcn = createCallbackFcn(app, @RoeTimefromHospitalizationtoRecoverySliderValueChanging, true);
            app.RoeTimefromHospitalizationtoRecoverySlider.Value = 15.88;
            app.DPanel = uipanel(app.CompartmentalModelTab);
            app.DPanel.Title = '+ D';
            app.GammaDurationofBurialTimeSliderLabel = uilabel(app.DPanel);
            app.GammaDurationofBurialTimeSliderLabel.Text = 'Gamma: Duration of Burial Time';
            app.GammaDurationofBurialTimeSlider = uislider(app.DPanel);
            app.GammaDurationofBurialTimeSlider.Limits = [0 5];
            app.GammaDurationofBurialTimeSlider.MajorTicks = [0 1 2 3 4 5];
            app.GammaDurationofBurialTimeSlider.ValueChangedFcn = createCallbackFcn(app, @GammaDurationofBurialTimeSliderValueChanged, true);
            app.GammaDurationofBurialTimeSlider.Value = 2.01;
            app.FPanel = uipanel(app.CompartmentalModelTab);
            app.FPanel.Title = '+ F';
            app.BetaFFuneralContactRateSliderLabel = uilabel(app.FPanel);
            app.BetaFFuneralContactRateSliderLabel.Text = 'Beta(F): Funeral Contact Rate ';
            app.BetaFFuneralContactRateSlider = uislider(app.FPanel);
            app.BetaFFuneralContactRateSlider.Limits = [0 1];
            app.BetaFFuneralContactRateSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.BetaFFuneralContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @BetaFFuneralContactRateSliderValueChanged, true);
            app.BetaFFuneralContactRateSlider.Value = 0.489;
            app.INITIALCONDITIONSLabel = uilabel(app.CompartmentalModelTab);
            app.INITIALCONDITIONSLabel.Text = 'INITIAL CONDITIONS';
            app.EKnobLabel = uilabel(app.CompartmentalModelTab);
            app.EKnobLabel.Text = 'E';
            app.EKnob = uiknob(app.CompartmentalModelTab, 'continuous');
            app.EKnob.ValueChangedFcn = createCallbackFcn(app, @EKnobValueChanged, true);
            app.EKnob.Value = 32;
            app.AgentbasedModelTab = uitab(app.TabGroup);
            app.AgentbasedModelTab.Title = 'Agent-based Model';
            app.HybridModelTab = uitab(app.TabGroup);
            app.HybridModelTab.Title = 'Hybrid Model';
            app.ValidationTab = uitab(app.TabGroup);
            app.ValidationTab.Title = 'Validation';
            app.CompartmentsButtonGroup_2 = uibuttongroup(app.ValidationTab);
            app.CompartmentsButtonGroup_2.Title = 'Compartments';
            app.SEIRButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIRButton_2.Text = 'SEIR';
            app.SEIRButton_2.Value = true;
            app.SEIHRButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRButton_2.Text = 'SEIHR';
            app.SEIRDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIRDButton_2.Text = 'SEIRD';
            app.SEIHRDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRDButton_2.Text = 'SEIHRD';
            app.SEIHRFDButton_2 = uiradiobutton(app.CompartmentsButtonGroup_2);
            app.SEIHRFDButton_2.Text = 'SEIHRFD';
            app.UIAxes_2 = uiaxes(app.ValidationTab);
            title(app.UIAxes_2, 'Model vs. Reality')
            xlabel(app.UIAxes_2, 'Time (days)')
            ylabel(app.UIAxes_2, 'Number of people')
            app.INPUTPARAMETERSLabel_2 = uilabel(app.ValidationTab);
            app.INPUTPARAMETERSLabel_2.Text = 'INPUT PARAMETERS';
            app.CONTROLSLabel_2 = uilabel(app.ValidationTab);
            app.CONTROLSLabel_2.Text = 'CONTROLS';
            app.MODELSLabel_2 = uilabel(app.ValidationTab);
            app.MODELSLabel_2.Text = 'MODELS';
            app.PLOTLabel_2 = uilabel(app.ValidationTab);
            app.PLOTLabel_2.Text = 'PLOT';
            app.CLEARButton_2 = uibutton(app.ValidationTab, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.NTotalPopulationSlider_2Label = uilabel(app.ValidationTab);
            app.NTotalPopulationSlider_2Label.Text = 'N: Total Population';
            app.NTotalPopulationSlider_2 = uislider(app.ValidationTab);
            app.NTotalPopulationSlider_2.Limits = [0 10000];
            app.NTotalPopulationSlider_2.ValueChangedFcn = createCallbackFcn(app, @NTotalPopulationSlider_2ValueChanged, true);
            app.NTotalPopulationSlider_2.Value = 3900;
            app.BetaCommunityContactRateSlider_2 = uislider(app.ValidationTab);
            app.BetaCommunityContactRateSlider_2.Limits = [0 1];
            app.BetaCommunityContactRateSlider_2.ValueChangedFcn = createCallbackFcn(app, @BetaCommunityContactRateSlider_2ValueChanged, true);
            app.BetaCommunityContactRateSlider_2.Value = 0.23;
            app.STARTButton_2 = uibutton(app.ValidationTab, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.LOADDATALabel = uilabel(app.ValidationTab);
            app.LOADDATALabel.Text = 'LOAD DATA';
            app.CDCCumulativeDataButtonGroup = uibuttongroup(app.ValidationTab);
            app.CDCCumulativeDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CDCCumulativeDataButtonGroupSelectionChanged, true);
            app.CDCCumulativeDataButtonGroup.Title = 'CDC Cumulative Data';
            app.Liberia2014Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Liberia2014Button.Text = 'Liberia 2014';
            app.Liberia2014Button.Value = true;
            app.Liberia2015Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Liberia2015Button.Text = 'Liberia 2015';
            app.Liberia2016Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Liberia2016Button.Text = 'Liberia 2016';
            app.Guinea2014Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Guinea2014Button.Text = 'Guinea 2014';
            app.Guinea2015Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Guinea2015Button.Text = 'Guinea 2015';
            app.Guinea2016Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.Guinea2016Button.Text = 'Guinea 2016';
            app.SierraLeone2014Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.SierraLeone2014Button.Text = 'Sierra Leone 2014';
            app.SierraLeone2015Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.SierraLeone2015Button.Text = 'Sierra Leone 2015';
            app.SierraLeone2016Button = uiradiobutton(app.CDCCumulativeDataButtonGroup);
            app.SierraLeone2016Button.Text = 'Sierra Leone 2016';
            app.RMSETextAreaLabel = uilabel(app.ValidationTab);
            app.RMSETextAreaLabel.Text = 'RMSE';
            app.RMSETextArea = uitextarea(app.ValidationTab);
            app.RMSETextArea.ValueChangedFcn = createCallbackFcn(app, @RMSETextAreaValueChanged, true);
            app.ERRORLabel = uilabel(app.ValidationTab);
            app.ERRORLabel.Text = 'ERROR';
            app.BetaCommunityContactRateSlider_2Label_3 = uilabel(app.ValidationTab);
            app.BetaCommunityContactRateSlider_2Label_3.Text = 'Beta(C): Community Contact Rate';
            app.MODELSPECIFICPARAMETERSLabel_2 = uilabel(app.ValidationTab);
            app.MODELSPECIFICPARAMETERSLabel_2.Text = 'MODEL-SPECIFIC PARAMETERS';
            app.SEIRPanel_2 = uipanel(app.ValidationTab);
            app.SEIRPanel_2.Title = 'SEIR';
            app.AlphaIncubationPeriodSlider_2Label = uilabel(app.SEIRPanel_2);
            app.AlphaIncubationPeriodSlider_2Label.Text = 'Alpha: Incubation Period ';
            app.AlphaIncubationPeriodSlider_2 = uislider(app.SEIRPanel_2);
            app.AlphaIncubationPeriodSlider_2.Limits = [2 21];
            app.AlphaIncubationPeriodSlider_2.MajorTicks = [2 5 8 11 14 17 21];
            app.AlphaIncubationPeriodSlider_2.ValueChangedFcn = createCallbackFcn(app, @AlphaIncubationPeriodSlider_2ValueChanged, true);
            app.AlphaIncubationPeriodSlider_2.Value = 12;
            app.ThetaProbabilityofSurvivalSlider_2Label = uilabel(app.SEIRPanel_2);
            app.ThetaProbabilityofSurvivalSlider_2Label.Text = 'Theta: Probability of Survival';
            app.ThetaProbabilityofSurvivalSlider_2 = uislider(app.SEIRPanel_2);
            app.ThetaProbabilityofSurvivalSlider_2.Limits = [0 1];
            app.ThetaProbabilityofSurvivalSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ThetaProbabilityofSurvivalSlider_2.ValueChangedFcn = createCallbackFcn(app, @ThetaProbabilityofSurvivalSlider_2ValueChanged, true);
            app.ThetaProbabilityofSurvivalSlider_2.Value = 0.549;
            app.PiProbabilityofSeekingHospitalizationSlider_2Label = uilabel(app.SEIRPanel_2);
            app.PiProbabilityofSeekingHospitalizationSlider_2Label.Text = 'Pi: Probability of Seeking Hospitalization';
            app.PiProbabilityofSeekingHospitalizationSlider_2 = uislider(app.SEIRPanel_2);
            app.PiProbabilityofSeekingHospitalizationSlider_2.Limits = [0 1];
            app.PiProbabilityofSeekingHospitalizationSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PiProbabilityofSeekingHospitalizationSlider_2.ValueChangedFcn = createCallbackFcn(app, @PiProbabilityofSeekingHospitalizationSlider_2ValueChanged, true);
            app.PiProbabilityofSeekingHospitalizationSlider_2.Value = 0.197;
            app.e1TimefromInfectiontoRecoverySlider_2Label = uilabel(app.SEIRPanel_2);
            app.e1TimefromInfectiontoRecoverySlider_2Label.Text = 'e1: Time from Infection to Recovery';
            app.e1TimefromInfectiontoRecoverySlider_2 = uislider(app.SEIRPanel_2);
            app.e1TimefromInfectiontoRecoverySlider_2.Limits = [0 20];
            app.e1TimefromInfectiontoRecoverySlider_2.MajorTicks = [0 5 10 15 20];
            app.e1TimefromInfectiontoRecoverySlider_2.ValueChangedFcn = createCallbackFcn(app, @e1TimefromInfectiontoRecoverySlider_2ValueChanged, true);
            app.e1TimefromInfectiontoRecoverySlider_2.Value = 15;
            app.e2k2TimeuntilHospitalizationSlider_2Label = uilabel(app.SEIRPanel_2);
            app.e2k2TimeuntilHospitalizationSlider_2Label.Text = 'e2, k2: Time until Hospitalization';
            app.e2k2TimeuntilHospitalizationSlider_2 = uislider(app.SEIRPanel_2);
            app.e2k2TimeuntilHospitalizationSlider_2.Limits = [0 5];
            app.e2k2TimeuntilHospitalizationSlider_2.MajorTicks = [0 1 2 3 4 5];
            app.e2k2TimeuntilHospitalizationSlider_2.ValueChangedFcn = createCallbackFcn(app, @e2k2TimeuntilHospitalizationSlider_2ValueChanged, true);
            app.e2k2TimeuntilHospitalizationSlider_2.Value = 3.24;
            app.k1TimefromInfectiontoDeathSlider_2Label = uilabel(app.SEIRPanel_2);
            app.k1TimefromInfectiontoDeathSlider_2Label.Text = 'k1: Time from Infection to Death ';
            app.k1TimefromInfectiontoDeathSlider_2 = uislider(app.SEIRPanel_2);
            app.k1TimefromInfectiontoDeathSlider_2.Limits = [0 30];
            app.k1TimefromInfectiontoDeathSlider_2.MajorTicks = [0 5 10 15 20 25 30];
            app.k1TimefromInfectiontoDeathSlider_2.ValueChangedFcn = createCallbackFcn(app, @k1TimefromInfectiontoDeathSlider_2ValueChanged, true);
            app.k1TimefromInfectiontoDeathSlider_2.Value = 13.31;
            app.HPanel_2 = uipanel(app.ValidationTab);
            app.HPanel_2.Title = '+ H';
            app.BetaHHospitalContactRateSlider_2Label = uilabel(app.HPanel_2);
            app.BetaHHospitalContactRateSlider_2Label.Text = 'Beta(H): Hospital Contact Rate';
            app.BetaHHospitalContactRateSlider_2 = uislider(app.HPanel_2);
            app.BetaHHospitalContactRateSlider_2.Limits = [0 0.1];
            app.BetaHHospitalContactRateSlider_2.MajorTicks = [0 0.025 0.05 0.075 0.1];
            app.BetaHHospitalContactRateSlider_2.ValueChangedFcn = createCallbackFcn(app, @BetaHHospitalContactRateSlider_2ValueChanged, true);
            app.BetaHHospitalContactRateSlider_2.Value = 0.062;
            app.DeltaTimefromHospitalizationtoDeathSlider_2Label = uilabel(app.HPanel_2);
            app.DeltaTimefromHospitalizationtoDeathSlider_2Label.Text = 'Delta: Time from Hospitalization to Death ';
            app.DeltaTimefromHospitalizationtoDeathSlider_2 = uislider(app.HPanel_2);
            app.DeltaTimefromHospitalizationtoDeathSlider_2.Limits = [0 20];
            app.DeltaTimefromHospitalizationtoDeathSlider_2.MajorTicks = [0 4 8 12 16 20];
            app.DeltaTimefromHospitalizationtoDeathSlider_2.ValueChangedFcn = createCallbackFcn(app, @DeltaTimefromHospitalizationtoDeathSlider_2ValueChanged, true);
            app.DeltaTimefromHospitalizationtoDeathSlider_2.Value = 10.07;
            app.RoeTimefromHospitalizationtoRecoverySlider_2Label = uilabel(app.HPanel_2);
            app.RoeTimefromHospitalizationtoRecoverySlider_2Label.Text = 'Roe: Time from Hospitalization to Recovery ';
            app.RoeTimefromHospitalizationtoRecoverySlider_2 = uislider(app.HPanel_2);
            app.RoeTimefromHospitalizationtoRecoverySlider_2.Limits = [0 20];
            app.RoeTimefromHospitalizationtoRecoverySlider_2.MajorTicks = [0 4 8 12 16 20];
            app.RoeTimefromHospitalizationtoRecoverySlider_2.ValueChangedFcn = createCallbackFcn(app, @RoeTimefromHospitalizationtoRecoverySlider_2ValueChanged, true);
            app.RoeTimefromHospitalizationtoRecoverySlider_2.Value = 15.88;
            app.DPanel_2 = uipanel(app.ValidationTab);
            app.DPanel_2.Title = '+ D';
            app.GammaDurationofBurialTimeSlider_2Label = uilabel(app.DPanel_2);
            app.GammaDurationofBurialTimeSlider_2Label.Text = 'Gamma: Duration of Burial Time';
            app.GammaDurationofBurialTimeSlider_2 = uislider(app.DPanel_2);
            app.GammaDurationofBurialTimeSlider_2.Limits = [0 5];
            app.GammaDurationofBurialTimeSlider_2.MajorTicks = [0 1 2 3 4 5];
            app.GammaDurationofBurialTimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @GammaDurationofBurialTimeSlider_2ValueChanged, true);
            app.GammaDurationofBurialTimeSlider_2.Value = 2.01;
            app.FPanel_2 = uipanel(app.ValidationTab);
            app.FPanel_2.Title = '+ F';
            app.BetaFFuneralContactRateSlider_2Label = uilabel(app.FPanel_2);
            app.BetaFFuneralContactRateSlider_2Label.Text = 'Beta(F): Funeral Contact Rate ';
            app.BetaFFuneralContactRateSlider_2 = uislider(app.FPanel_2);
            app.BetaFFuneralContactRateSlider_2.Limits = [0 1];
            app.BetaFFuneralContactRateSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.BetaFFuneralContactRateSlider_2.ValueChangedFcn = createCallbackFcn(app, @BetaFFuneralContactRateSlider_2ValueChanged, true);
            app.BetaFFuneralContactRateSlider_2.Value = 0.489;
            app.INITIALCONDITIONSLabel_2 = uilabel(app.ValidationTab);
            app.INITIALCONDITIONSLabel_2.Text = 'INITIAL CONDITIONS';
            app.EKnob_2Label = uilabel(app.ValidationTab);
            app.EKnob_2Label.Text = 'E';
            app.EKnob_2 = uiknob(app.ValidationTab, 'continuous');
            app.EKnob_2.ValueChangedFcn = createCallbackFcn(app, @EKnob_2ValueChanged, true);
            app.EKnob_2.Value = 32;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end